classdef MN_PVI_GUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        FileMenu              matlab.ui.container.Menu
        RelatrioMenu          matlab.ui.container.Menu
        ExcelMenu             matlab.ui.container.Menu
        DarkModeMenu          matlab.ui.container.Menu
        SairMenu              matlab.ui.container.Menu
        SobreMenu             matlab.ui.container.Menu
        AutoresMenu           matlab.ui.container.Menu
        UIMenu                matlab.ui.container.Menu
        DarkModeMenu_2        matlab.ui.container.Menu
        LightModeMenu         matlab.ui.container.Menu
        GridLayout            matlab.ui.container.GridLayout
        LeftPanel             matlab.ui.container.Panel
        GrelhaCheckBox        matlab.ui.control.CheckBox
        ResetButton           matlab.ui.control.Button
        ExecutarButton        matlab.ui.control.Button
        bgMNumericos          matlab.ui.container.ButtonGroup
        TodososMetodosButton  matlab.ui.control.ToggleButton
        RK3Button             matlab.ui.control.ToggleButton
        ODE45Button           matlab.ui.control.ToggleButton
        RK4Button             matlab.ui.control.ToggleButton
        RK2Button             matlab.ui.control.ToggleButton
        EulerMButton          matlab.ui.control.ToggleButton
        EulerButton           matlab.ui.control.ToggleButton
        DadosdoPVIPanel       matlab.ui.container.Panel
        aEditFieldLabel_4     matlab.ui.control.Label
        eN                    matlab.ui.control.NumericEditField
        eY0                   matlab.ui.control.NumericEditField
        aEditFieldLabel_3     matlab.ui.control.Label
        eB                    matlab.ui.control.NumericEditField
        aEditFieldLabel_2     matlab.ui.control.Label
        eA                    matlab.ui.control.NumericEditField
        aEditFieldLabel       matlab.ui.control.Label
        eF                    matlab.ui.control.EditField
        ftyEditFieldLabel     matlab.ui.control.Label
        RightPanel            matlab.ui.container.Panel
        GridLayout2           matlab.ui.container.GridLayout
        UITable               matlab.ui.control.Table
        UIAxes                matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            ExecutarButtonPushed(app, [])
        end

        % Callback function: ExecutarButton, bgMNumericos
        function ExecutarButtonPushed(app, event)
            syms y(t)
            strF = app.eF.Value;
            f =@(t,y) eval(vectorize(strF));
            %f = matlabFunction(str2func(strF));
            a = app.eA.Value;
            b = app.eB.Value;
            n = app.eN.Value;
            y0 = app.eY0.Value;

            MNumericoSelecionado = app.bgMNumericos.SelectedObject.Text;

            erroCounter = 0;
            erroString = ' ';

            try
                try
                    z = f(t,y);
                    app.eF.BackgroundColor = "w";
                catch
                    app.eF.BackgroundColor = [1,0,0];
                    erroCounter = erroCounter + 1;
                    erroString = erroString + "Introduza uma função válida em t e y" + newline;
                end

                if b<=a
                    app.eB.BackgroundColor = "r";
                    app.eA.BackgroundColor = "r";
                    erroCounter = erroCounter + 1;
                    erroString = erroString + "Introduza um intervalo com b > a" + newline;
                else
                    app.eB.BackgroundColor = [1,1,1];
                    app.eA.BackgroundColor = [1,1,1];
                end

                if n<2
                    app.eN.BackgroundColor = "r";
                    erroCounter = erroCounter + 1;
                    erroString = erroString + "Introduza um N ≥ 2" + newline;
                else
                    app.eN.BackgroundColor = [1,1,1];
                end
                if(erroCounter > 0)
                    errordlg(erroString, "Erro de Validação", "modal");
                    return;
                end


                sExata = dsolve(diff(y,t)==f(t,y),y(a)==y0);
                g = matlabFunction(sExata);

                [t,yEuler] = NEuler(f,a,b,n,y0);
                [~,yEulerM] = NEulerM(f,a,b,n,y0);
                [~,yRK2] = NRK2(f,a,b,n,y0);
                [~,yRK3] = NRK3(f,a,b,n,y0);
                [~,yRK4] = NRK4(f,a,b,n,y0);
                [~,yODE45]   = mODE45(f,a,b,n,y0);

                yExata = g(t);

                erroEuler = abs(yExata-yEuler);
                erroEulerM = abs(yExata - yEulerM);
                erroRK2 = abs(yExata-yRK2);
                erroRK3 = abs(yExata - yRK3);
                erroRK4 = abs(yExata - yRK4);
                erroODE45 = abs(yExata - yODE45);

                plot(app.UIAxes,t,yExata,"-ro")
                hold(app.UIAxes,"on")

                % % Definir a função y(t)
                % farolA = 2 * exp(-t.^2);
                %
                % % Plotar o gráfico
                % plot(t, farolA, 'b', 'LineWidth', 2);

                switch MNumericoSelecionado
                    case "Euler"
                        plot(app.UIAxes,t,yEuler,"-b*")
                        legendaGrafico = ["Exata","Euler"];
                        A = [t.',yExata.',yEuler.',erroEuler.'];
                        nomeColunasTabela = ["t","Exata","Euler","erroEuler"];
                    case "EulerM"
                        plot(app.UIAxes,t,yEulerM,"-b*")
                        legendaGrafico = ["Exata","erroEulerM"];
                        A = [t.',yExata.',yEulerM.',erroEulerM.'];
                        nomeColunasTabela = ["t","Exata","EulerM","erroEuler"];
                    case "RK2"
                        plot(app.UIAxes,t,yRK2,"-b*")
                        legendaGrafico = ["Exata","RK2"];
                        A = [t.',yExata.',yRK2.',erroRK2.'];
                        nomeColunasTabela = ["t","Exata","RK2","erroRK2"];
                    case "RK3"
                        plot(app.UIAxes,t,yRK3,"-b*")
                        legendaGrafico = ["Exata","RK3"];
                        A = [t.',yExata.',yRK3.',erroRK3.'];
                        nomeColunasTabela = ["t","Exata","RK3","erroRK3"];
                    case "RK4"
                        plot(app.UIAxes,t,yRK4,"-b*")
                        legendaGrafico = ["Exata","RK4"];
                        A = [t.',yExata.',yRK4.',erroRK4.'];
                        nomeColunasTabela = ["t","Exata","RK4","erroRK4"];
                    case "ODE45"
                        plot(app.UIAxes,t,yODE45,"-b*")
                        legendaGrafico = ["Exata","ODE45"];
                        A = [t.',yExata.',yODE45.',erroODE45.'];
                        nomeColunasTabela = ["t","Exata","ODE45","erroODE45"];
                    case "Todos os Métodos"
                        plot(app.UIAxes,t,yEuler,"-b*")
                        plot(app.UIAxes,t,yEulerM,"--mo")
                        plot(app.UIAxes,t,yRK2,"-g+")
                        plot(app.UIAxes,t,yRK3,":r^")
                        plot(app.UIAxes,t,yRK4,"-.k*")
                        plot(app.UIAxes,t,yODE45,"--bs")

                        legendaGrafico = ["Exata","Euler","EulerM","RK2","RK3","RK4","ODE45"];
                        A = [t.',yExata.',yEuler.',yEulerM.',yRK2.', yRK3.', yRK4.', yODE45.',erroEuler.',erroEulerM.',erroRK2.',erroRK3.',erroRK4.',erroODE45.'];
                        nomeColunasTabela = ["t","Exata","Euler","EulerM","RK2","RK3","RK4","ODE45","erroEuler","erroEulerM","erroRK2","erroRK3","erroRK4","erroODE45"];
                end

                hold(app.UIAxes,"off")
                legend(app.UIAxes,legendaGrafico)

                tabelaResultados = array2table(A);
                app.UITable.Data = tabelaResultados;
                app.UITable.ColumnName = nomeColunasTabela;

            catch Me
                errordlg(Me.message,"Erro nos dados do PVI","modal")
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.eF.Value = "y+exp(3*t)";
            app.eA.Value = 1;
            app.eB.Value = 1.5;
            app.eN.Value = 2;
            app.eY0.Value = 2;
            ExecutarButtonPushed(app, event)
        end

        % Value changed function: GrelhaCheckBox
        function GrelhaCheckBoxValueChanged(app, event)
            value = app.GrelhaCheckBox.Value;
            if value
                grid(app.UIAxes,"on")
            else
                grid(app.UIAxes,"off")
            end
        end

        % Menu selected function: SairMenu
        function SairMenuSelected(app, event)
            SN = questdlg("Quer sair?","SAIR","Sim","Não","Sim");
            if strcmp(SN,"Sim")
                delete(app)
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            %delete(app)
            SairMenuSelected(app, event)
        end

        % Menu selected function: RelatrioMenu
        function RelatrioMenuSelected(app, event)
            winopen("relatorio.pdf");
        end

        % Menu selected function: ExcelMenu
        function ExcelMenuSelected(app, event)
            filename = 'resultado.xlsx';

            if isfile(filename)
                delete(filename);
            end

            funcStr = app.eF.Value;
            cTabela = get(app.UITable, 'Data');
            headers = get(app.UITable, 'ColumnName');


            if isstring(headers) || ischar(headers)
                headers = cellstr(headers);
            end
            headers = reshape(headers, 1, []);
            numColsData = size(cTabela, 2);
            numColsHeaders = length(headers);
            if numColsHeaders ~= numColsData
                msgbox('Erro: Número de colunas dos cabeçalhos não corresponde ao número de colunas da tabela.', 'Erro', 'error');
                return;
            end

            metaInfo = cell(1, numColsHeaders);
            metaInfo{1} = ['Função f(t,y): ', funcStr];

            linhaBranco = cell(1, numColsHeaders);

            dataToWrite = [metaInfo; linhaBranco; headers; table2cell(cTabela)];
            writecell(dataToWrite, filename);

            msgbox('Tabela exportada para resultado.xlsx', 'Sucesso');
            winopen(filename);
        end

        % Menu selected function: AutoresMenu
        function AutoresMenuSelected(app, event)
            Autores_App
        end

        % Menu selected function: LightModeMenu
        function LightModeMenuSelected(app, event)
            app.LeftPanel.BackgroundColor = [0.94 0.94 0.94];
            app.bgMNumericos.BackgroundColor = [0.94 0.94 0.94];
            app.DadosdoPVIPanel.BackgroundColor = [0.94 0.94 0.94];
            app.GridLayout2.BackgroundColor = [0.94 0.94 0.94];
        end

        % Menu selected function: DarkModeMenu_2
        function DarkModeMenuSelected(app, event)
            app.LeftPanel.BackgroundColor = [0.5 0.5 0.5];
            app.bgMNumericos.BackgroundColor = [0.65 0.65 0.65];
            app.DadosdoPVIPanel.BackgroundColor = [0.65 0.65 0.65];
            app.GridLayout2.BackgroundColor = [0.5 0.5 0.5];
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {378, 378};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.RelatrioMenu = uimenu(app.FileMenu);
            app.RelatrioMenu.MenuSelectedFcn = createCallbackFcn(app, @RelatrioMenuSelected, true);
            app.RelatrioMenu.Text = 'Relatório';
            app.ExcelMenu = uimenu(app.FileMenu);
            app.ExcelMenu.MenuSelectedFcn = createCallbackFcn(app, @ExcelMenuSelected, true);
            app.ExcelMenu.Text = 'Excel';
            app.DarkModeMenu = uimenu(app.FileMenu);
            app.DarkModeMenu.Text = 'DarkMode';
            app.SairMenu = uimenu(app.FileMenu);
            app.SairMenu.MenuSelectedFcn = createCallbackFcn(app, @SairMenuSelected, true);
            app.SairMenu.Text = 'Sair';
            app.SobreMenu = uimenu(app.UIFigure);
            app.SobreMenu.Text = 'Sobre';
            app.AutoresMenu = uimenu(app.SobreMenu);
            app.AutoresMenu.MenuSelectedFcn = createCallbackFcn(app, @AutoresMenuSelected, true);
            app.AutoresMenu.Text = 'Autores';
            app.UIMenu = uimenu(app.UIFigure);
            app.UIMenu.Text = 'UI';
            app.DarkModeMenu_2 = uimenu(app.UIMenu);
            app.DarkModeMenu_2.MenuSelectedFcn = createCallbackFcn(app, @DarkModeMenuSelected, true);
            app.DarkModeMenu_2.Text = 'DarkMode';
            app.LightModeMenu = uimenu(app.UIMenu);
            app.LightModeMenu.MenuSelectedFcn = createCallbackFcn(app, @LightModeMenuSelected, true);
            app.LightModeMenu.Text = 'LightMode';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DadosdoPVIPanel = uipanel(app.LeftPanel);
            app.DadosdoPVIPanel.Title = 'Dados do PVI';
            app.ftyEditFieldLabel = uilabel(app.DadosdoPVIPanel);
            app.ftyEditFieldLabel.Text = 'f(t,y) = ';
            app.eF = uieditfield(app.DadosdoPVIPanel, 'text');
            app.eF.Value = 'y +exp(3*t)';
            app.aEditFieldLabel = uilabel(app.DadosdoPVIPanel);
            app.aEditFieldLabel.Text = 'a';
            app.eA = uieditfield(app.DadosdoPVIPanel, 'numeric');
            app.eA.Value = 1;
            app.aEditFieldLabel_2 = uilabel(app.DadosdoPVIPanel);
            app.aEditFieldLabel_2.Text = 'b';
            app.eB = uieditfield(app.DadosdoPVIPanel, 'numeric');
            app.eB.Value = 1.5;
            app.aEditFieldLabel_3 = uilabel(app.DadosdoPVIPanel);
            app.aEditFieldLabel_3.Text = 'y0';
            app.eY0 = uieditfield(app.DadosdoPVIPanel, 'numeric');
            app.eY0.Value = 2;
            app.eN = uieditfield(app.DadosdoPVIPanel, 'numeric');
            app.eN.Value = 2;
            app.aEditFieldLabel_4 = uilabel(app.DadosdoPVIPanel);
            app.aEditFieldLabel_4.Text = 'n';
            app.bgMNumericos = uibuttongroup(app.LeftPanel);
            app.bgMNumericos.SelectionChangedFcn = createCallbackFcn(app, @ExecutarButtonPushed, true);
            app.bgMNumericos.Title = 'Métodos Numéricos';
            app.EulerButton = uitogglebutton(app.bgMNumericos);
            app.EulerButton.Text = 'Euler';
            app.EulerButton.Value = true;
            app.EulerMButton = uitogglebutton(app.bgMNumericos);
            app.EulerMButton.Text = 'EulerM';
            app.RK2Button = uitogglebutton(app.bgMNumericos);
            app.RK2Button.Text = 'RK2';
            app.RK4Button = uitogglebutton(app.bgMNumericos);
            app.RK4Button.Text = 'RK4';
            app.ODE45Button = uitogglebutton(app.bgMNumericos);
            app.ODE45Button.Text = 'ODE45';
            app.RK3Button = uitogglebutton(app.bgMNumericos);
            app.RK3Button.Text = 'RK3';
            app.TodososMetodosButton = uitogglebutton(app.bgMNumericos);
            app.TodososMetodosButton.Text = 'Todos os Métodos';
            app.ExecutarButton = uibutton(app.LeftPanel, 'push');
            app.ExecutarButton.ButtonPushedFcn = createCallbackFcn(app, @ExecutarButtonPushed, true);
            app.ExecutarButton.Text = 'Executar';
            app.ResetButton = uibutton(app.LeftPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.GrelhaCheckBox = uicheckbox(app.LeftPanel);
            app.GrelhaCheckBox.ValueChangedFcn = createCallbackFcn(app, @GrelhaCheckBoxValueChanged, true);
            app.GrelhaCheckBox.Text = 'Grelha';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.UIAxes = uiaxes(app.GridLayout2);
            xlabel(app.UIAxes, 'T')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UITable = uitable(app.GridLayout2);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MN_PVI_GUI_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end