classdef appMaquina01MNSED < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        FileMenu                     matlab.ui.container.Menu
        RelatrioMenu                 matlab.ui.container.Menu
        ExcelMenu                    matlab.ui.container.Menu
        SairMenu                     matlab.ui.container.Menu
        SobreMenu                    matlab.ui.container.Menu
        AutoresMenu                  matlab.ui.container.Menu
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        SistemaDinamicoDropDown      matlab.ui.control.DropDown
        SistemaDinmicoDropDownLabel  matlab.ui.control.Label
        GrelhaCheckBox               matlab.ui.control.CheckBox
        ResetButton                  matlab.ui.control.Button
        ExecutarButton               matlab.ui.control.Button
        bgMNumericos                 matlab.ui.container.ButtonGroup
        TodososMetodosButton         matlab.ui.control.ToggleButton
        RK3Button                    matlab.ui.control.ToggleButton
        ODE45Button                  matlab.ui.control.ToggleButton
        RK4Button                    matlab.ui.control.ToggleButton
        RK2Button                    matlab.ui.control.ToggleButton
        EulerMButton                 matlab.ui.control.ToggleButton
        EulerButton                  matlab.ui.control.ToggleButton
        DadosdoPVIPanel              matlab.ui.container.Panel
        nSpinner                     matlab.ui.control.Spinner
        nSpinnerLabel                matlab.ui.control.Label
        eV0                          matlab.ui.control.NumericEditField
        aEditFieldLabel_4            matlab.ui.control.Label
        eG                           matlab.ui.control.EditField
        ftuvLabel_2                  matlab.ui.control.Label
        eU0                          matlab.ui.control.NumericEditField
        aEditFieldLabel_3            matlab.ui.control.Label
        eB                           matlab.ui.control.NumericEditField
        aEditFieldLabel_2            matlab.ui.control.Label
        eA                           matlab.ui.control.NumericEditField
        aEditFieldLabel              matlab.ui.control.Label
        eF                           matlab.ui.control.EditField
        ftuvLabel                    matlab.ui.control.Label
        RightPanel                   matlab.ui.container.Panel
        GridLayout2                  matlab.ui.container.GridLayout
        UITable                      matlab.ui.control.Table
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        PodeCalcularExata = true 
    end

    methods (Access = public)

        function [t,u,v] = NEulerSED(~,f,g,a,b,n,u0,v0)
            h = (b-a)/n;
            t = a:h:b;
            u = zeros(1,n+1);
            v = zeros(1,n+1);
            u(1) = u0;
            v(1) = v0;
            for i = 1:n
                u(i+1) = u(i)+h*f(t(i),u(i),v(i));
                v(i+1) = v(i)+h*g(t(i),u(i),v(i));
            end
        end

        function [t,u,v] = NEulerMSED(~,f,g,a,b,n,u0,v0)
            h = (b-a)/n;
            t = a:h:b;
            u = zeros(1,n+1);
            v = zeros(1,n+1);
            u(1) = u0;
            v(1) = v0;
            for i = 1:n
                k1u = f(t(i),u(i),v(i));
                k1v = g(t(i),u(i),v(i));
                k2u = f(t(i+1), u(i) + h*k1u, v(i) + h*k1v);
                k2v = g(t(i+1), u(i) + h*k1u, v(i) + h*k1v);
                u(i+1) = u(i) + h*(k1u + k2u)/2;
                v(i+1) = v(i) + h*(k1v + k2v)/2;
            end
        end

        function [t,u,v] = NRK2SED(~,f,g,a,b,n,u0,v0)
            h = (b-a)/n;
            t = a:h:b;
            u = zeros(1,n+1);
            v = zeros(1,n+1);
            u(1) = u0;
            v(1) = v0;
            for i = 1:n
                k1u = h*f(t(i),u(i),v(i));
                k1v = h*g(t(i),u(i),v(i));
                k2u = h*f(t(i+1),u(i)+k1u,v(i)+k1v);
                k2v = h*g(t(i+1),u(i)+k1u,v(i)+k1v);
                u(i+1) = u(i)+(k1u+k2u)/2;
                v(i+1) = v(i)+(k1v+k2v)/2;
            end
        end

        function [t,u,v] = NRK3SED(~,f,g,a,b,n,u0,v0)
            h = (b-a)/n;
            t = a:h:b;
            u = zeros(1,n+1);
            v = zeros(1,n+1);
            u(1) = u0;
            v(1) = v0;
            for i = 1:n
                k1u = h*f(t(i), u(i), v(i));
                k1v = h*g(t(i), u(i), v(i));

                k2u = h*f(t(i)+h/2, u(i)+k1u/2, v(i)+k1v/2);
                k2v = h*g(t(i)+h/2, u(i)+k1u/2, v(i)+k1v/2);

                k3u = h*f(t(i)+h, u(i)-k1u+2*k2u, v(i)-k1v+2*k2v);
                k3v = h*g(t(i)+h, u(i)-k1u+2*k2u, v(i)-k1v+2*k2v);

                u(i+1) = u(i) + (k1u + 4*k2u + k3u)/6;
                v(i+1) = v(i) + (k1v + 4*k2v + k3v)/6;
            end
        end

        function [t,u,v] = NRK4SED(~,f,g,a,b,n,u0,v0)
            h = (b-a)/n;
            t = a:h:b;
            u = zeros(1,n+1);
            v = zeros(1,n+1);
            u(1) = u0;
            v(1) = v0;
            for i = 1:n
                k1u = h*f(t(i), u(i), v(i));
                k1v = h*g(t(i), u(i), v(i));

                k2u = h*f(t(i)+h/2, u(i)+k1u/2, v(i)+k1v/2);
                k2v = h*g(t(i)+h/2, u(i)+k1u/2, v(i)+k1v/2);

                k3u = h*f(t(i)+h/2, u(i)+k2u/2, v(i)+k2v/2);
                k3v = h*g(t(i)+h/2, u(i)+k2u/2, v(i)+k2v/2);

                k4u = h*f(t(i)+h, u(i)+k3u, v(i)+k3v);
                k4v = h*g(t(i)+h, u(i)+k3u, v(i)+k3v);

                u(i+1) = u(i) + (k1u + 2*k2u + 2*k3u + k4u)/6;
                v(i+1) = v(i) + (k1v + 2*k2v + 2*k3v + k4v)/6;
            end
        end

        function [t,u,v] = ODE45SED(~,f,g,a,b,n,u0,v0)
            h = (b-a)/n;
            tspan = a:(b-a)/n:b;
            y0 = [u0; v0];

            [T, Y] = ode45(@(t,y) [f(t,y(1),y(2)); g(t,y(1),y(2))], tspan, y0);

            t = T';
            u = Y(:,1)';
            v = Y(:,2)';
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            ExecutarButtonPushed(app, [])
        end

        % Callback function: ExecutarButton, bgMNumericos, nSpinner
        function ExecutarButtonPushed(app, event)
            % --- Callback ExecutarButtonPushed(app, event) ---

            % 1) === Validação de todas as entradas ===
            erroCounter = 0;
            erroString  = "";

            % Leitura dos campos
            strF = app.eF.Value;
            strG = app.eG.Value;
            a    = app.eA.Value;
            b    = app.eB.Value;
            n    = app.nSpinner.Value;
            u0   = app.eU0.Value;
            v0   = app.eV0.Value;

            % Valida f(t,u,v)
            try
                f = @(t,u,v) eval(vectorize(strF));
                % avalia em ponto de teste
                f(0,1,1);
                app.eF.BackgroundColor = [1,1,1];
            catch
                app.eF.BackgroundColor = [1,0.8,0.8];
                erroCounter = erroCounter + 1;
                erroString = erroString + "Introduza uma função válida em t, u e v para f." + newline;
            end

            % Valida g(t,u,v)
            try
                g = @(t,u,v) eval(vectorize(strG));
                g(0,1,1);
                app.eG.BackgroundColor = [1,1,1];
            catch
                app.eG.BackgroundColor = [1,0.8,0.8];
                erroCounter = erroCounter + 1;
                erroString = erroString + "Introduza uma função válida em t, u e v para g." + newline;
            end

            % Valida intervalo [a,b]
            if ~isnumeric(a) || ~isnumeric(b) || isnan(a) || isnan(b)
                app.eA.BackgroundColor = [1,0.8,0.8];
                app.eB.BackgroundColor = [1,0.8,0.8];
                erroCounter = erroCounter + 1;
                erroString = erroString + "Os campos a e b devem ser números válidos." + newline;
            elseif b <= a
                app.eA.BackgroundColor = [1,0.8,0.8];
                app.eB.BackgroundColor = [1,0.8,0.8];
                erroCounter = erroCounter + 1;
                erroString = erroString + "Deve ser b > a no intervalo." + newline;
            else
                app.eA.BackgroundColor = [1,1,1];
                app.eB.BackgroundColor = [1,1,1];
            end

            % Valida número de passos n
            if ~isnumeric(n) || n < 2 || floor(n)~=n
                app.nSpinner.BackgroundColor = [1,0.8,0.8];
                erroCounter = erroCounter + 1;
                erroString = erroString + "Introduza um N inteiro ≥ 2." + newline;
            else
                app.nSpinner.BackgroundColor = [1,1,1];
            end

            % Valida condições iniciais u0 e v0
            if ~isnumeric(u0) || isnan(u0) || ~isfinite(u0)
                app.eU0.BackgroundColor = [1,0.8,0.8];
                erroCounter = erroCounter + 1;
                erroString = erroString + "u0 deve ser um número finito." + newline;
            else
                app.eU0.BackgroundColor = [1,1,1];
            end
            if ~isnumeric(v0) || isnan(v0) || ~isfinite(v0)
                app.eV0.BackgroundColor = [1,0.8,0.8];
                erroCounter = erroCounter + 1;
                erroString = erroString + "v0 deve ser um número finito." + newline;
            else
                app.eV0.BackgroundColor = [1,1,1];
            end

            % Se houver erros, mostra diálogo e sai
            if erroCounter > 0
                errordlg(erroString, "Erro de Validação", "modal");
                return;
            end

            % 2) === Cálculo da solução exata e métodos numéricos ===
            syms u(t) v(t)
            SED = [diff(u,t)==f(t,u,v), diff(v,t)==g(t,u,v)];
            ci  = [u(a)==u0, v(a)==v0];

            % Chama os métodos
            [t,uEuler,~]    = NEulerSED(app,f,g,a,b,n,u0,v0);
            [~,uEulerM,~]   = NEulerMSED(app,f,g,a,b,n,u0,v0);
            [~,uRK2,~]      = NRK2SED(app,f,g,a,b,n,u0,v0);
            [~,uRK3,~]      = NRK3SED(app,f,g,a,b,n,u0,v0);
            [~,uRK4,~]      = NRK4SED(app,f,g,a,b,n,u0,v0);
            [~,uODE45,~]    = ODE45SED(app,f,g,a,b,n,u0,v0);

            cla(app.UIAxes)

            if app.PodeCalcularExata == true
                % 2) === Cálculo da solução exata
                [uExataSym, ~] = dsolve(SED, ci);
                h = matlabFunction(uExataSym);
                uExata = h(t);
                erroEuler  = abs(uExata - uEuler);
                erroEulerM = abs(uExata - uEulerM);
                erroRK2    = abs(uExata - uRK2);
                erroRK3    = abs(uExata - uRK3);
                erroRK4    = abs(uExata - uRK4);
                erroODE45  = abs(uExata - uODE45);
            
                plot(app.UIAxes, t, uExata, "-ro");
                hold(app.UIAxes, "on")
            end
            
            % 3) === Plot e tabela de resultados ===
            
            switch app.bgMNumericos.SelectedObject.Text
                case "Euler"
                    hold(app.UIAxes, "on")
                    plot(app.UIAxes, t, uEuler, "-b*");
                    if app.PodeCalcularExata
                        legenda = ["Exata","Euler"];
                        A = [t.',uExata.',uEuler.',erroEuler.'];
                        colnames = ["t","Exata","Euler","erroEuler"];
                    else
                        legenda = ["Euler"];
                        A = [t.',uEuler.'];
                        colnames = ["t","Euler"];
                    end
            
                case "EulerM"
                    hold(app.UIAxes, "on")
                    plot(app.UIAxes, t, uEulerM, "-b*");
                    if app.PodeCalcularExata
                        legenda = ["Exata","EulerM"];
                        A = [t.',uExata.',uEulerM.',erroEulerM.'];
                        colnames = ["t","Exata","EulerM","erroEulerM"];
                    else
                        legenda = ["EulerM"];
                        A = [t.',uEulerM.'];
                        colnames = ["t","EulerM"];
                    end
            
                case "RK2"
                    hold(app.UIAxes, "on")
                    plot(app.UIAxes, t, uRK2, "-b*");
                    if app.PodeCalcularExata
                        legenda = ["Exata","RK2"];
                        A = [t.',uExata.',uRK2.',erroRK2.'];
                        colnames = ["t","Exata","RK2","erroRK2"];
                    else
                        legenda = ["RK2"];
                        A = [t.',uRK2.'];
                        colnames = ["t","RK2"];
                    end
            
                case "RK3"
                    hold(app.UIAxes, "on")
                    plot(app.UIAxes, t, uRK3, "-b*");
                    if app.PodeCalcularExata
                        legenda = ["Exata","RK3"];
                        A = [t.',uExata.',uRK3.',erroRK3.'];
                        colnames = ["t","Exata","RK3","erroRK3"];
                    else
                        legenda = ["RK3"];
                        A = [t.',uRK3.'];
                        colnames = ["t","RK3"];
                    end
            
                case "RK4"
                    hold(app.UIAxes, "on")
                    plot(app.UIAxes, t, uRK4, "-b*");
                    if app.PodeCalcularExata
                        legenda = ["Exata","RK4"];
                        A = [t.',uExata.',uRK4.',erroRK4.'];
                        colnames = ["t","Exata","RK4","erroRK4"];
                    else
                        legenda = ["RK4"];
                        A = [t.',uRK4.'];
                        colnames = ["t","RK4"];
                    end
            
                case "ODE45"
                    hold(app.UIAxes, "on")
                    plot(app.UIAxes, t, uODE45, "-b*");
                    if app.PodeCalcularExata
                        legenda = ["Exata","ODE45"];
                        A = [t.',uExata.',uODE45.',erroODE45.'];
                        colnames = ["t","Exata","ODE45","erroODE45"];
                    else
                        legenda = ["ODE45"];
                        A = [t.',uODE45.'];
                        colnames = ["t","ODE45"];
                    end
            
                case "Todos os Métodos"
                    hold(app.UIAxes, "on")
                    if app.PodeCalcularExata
                        plot(app.UIAxes, t, uExata, "-ro");
                    end
                    plot(app.UIAxes, t, uEuler,  "-b*")
                    plot(app.UIAxes, t, uEulerM, "-gx")
                    plot(app.UIAxes, t, uRK2,    "-ms")
                    plot(app.UIAxes, t, uRK3,    "-c^")
                    plot(app.UIAxes, t, uRK4,    "-kd")
                    plot(app.UIAxes, t, uODE45,  "-yp")
                    if app.PodeCalcularExata
                        legenda = ["Exata","Euler","EulerM","RK2","RK3","RK4","ODE45"];
                        A = [t.',uExata.',uEuler.',uEulerM.',uRK2.',uRK3.',uRK4.',uODE45.', ...
                            erroEuler.',erroEulerM.',erroRK2.',erroRK3.',erroRK4.',erroODE45.'];
                        colnames = ["t","Exata","Euler","EulerM","RK2","RK3","RK4","ODE45", ...
                            "erroEuler","erroEulerM","erroRK2","erroRK3","erroRK4","erroODE45"];
                    else
                        legenda = ["Euler","EulerM","RK2","RK3","RK4","ODE45"];
                        A = [t.',uEuler.',uEulerM.',uRK2.',uRK3.',uRK4.',uODE45.'];
                        colnames = ["t","Euler","EulerM","RK2","RK3","RK4","ODE45"];
                    end
            end
            
            hold(app.UIAxes, "off")
            legend(app.UIAxes, legenda)

            % Atualiza tabela
            T = array2table(A);
            app.UITable.Data       = T;
            app.UITable.ColumnName = colnames;

        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            % app.eF.Value = "y+exp(3*t)";
            % app.eA.Value = 1;
            % app.eB.Value = 1.5;
            % app.nSpinner.Value = 2;
            % app.eU0.Value = 2;
            ExecutarButtonPushed(app, event)
        end

        % Value changed function: GrelhaCheckBox
        function GrelhaCheckBoxValueChanged(app, event)
            value = app.GrelhaCheckBox.Value;
            if value
                grid(app.UIAxes,"on")
            else
                grid(app.UIAxes,"off")
            end

        end

        % Menu selected function: SairMenu
        function SairMenuSelected(app, event)
            SN = questdlg("Quer sair?","SAIR","Sim","Não","Sim");
            if strcmp(SN,"Sim")
                delete(app)
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            %delete(app)
            SairMenuSelected(app, event)
        end

        % Menu selected function: RelatrioMenu
        function RelatrioMenuSelected(app, event)
            open("reltp4.pdf")
        end

        % Menu selected function: ExcelMenu
        function ExcelMenuSelected(app, event)
            filename = 'resultado.xlsx';

            if isfile(filename)
                delete(filename);
            end

            funcStr = app.eF.Value;
            funcStr2 = app.eG.Value;
            cTabela = get(app.UITable, 'Data');
            headers = get(app.UITable, 'ColumnName');


            if isstring(headers) || ischar(headers)
                headers = cellstr(headers);
            end
            headers = reshape(headers, 1, []);
            numColsData = size(cTabela, 2);
            numColsHeaders = length(headers);
            if numColsHeaders ~= numColsData
                msgbox('Erro: Número de colunas dos cabeçalhos não corresponde ao número de colunas da tabela.', 'Erro', 'error');
                return;
            end

            metaInfo1 = cell(1, numColsHeaders);
            metaInfo1{1} = ['Função f(t,u,v): ', funcStr];
            metaInfo2 = cell(2, numColsHeaders);
            metaInfo2{1} = ['Função g(t,u,v): ', funcStr2];


            linhaBranco = cell(1, numColsHeaders);

            dataToWrite = [metaInfo1;metaInfo2; linhaBranco; headers; table2cell(cTabela)];
            writecell(dataToWrite, filename);

            msgbox('Tabela exportada para resultado.xlsx', 'Sucesso');
            winopen(filename);
        end

        % Menu selected function: AutoresMenu
        function AutoresMenuSelected(app, event)
            appAutores
        end

        % Value changed function: SistemaDinamicoDropDown
        function SistemaDinamicoDropDownValueChanged(app, event)
            value = app.SistemaDinamicoDropDown.Value;
            switch value
                case '1' % Mola-Massa s/ Amortecimento: u'' + 16u = 0
                    strF = 'v';
                    strG = '-16*u';
                    a = 0; b = 8; n = 100; u0 = 9; v0 = 0;
                    app.PodeCalcularExata = true;

                case '2' % Mola-Massa c/ Amortecimento: u'' + 2*u' + 16u = 0
                    strF = 'v';
                    strG = '-2*v - 16*u';
                    a = 0; b = 10; n = 100; u0 = 1; v0 = 0;
                    app.PodeCalcularExata = true;

                case '3' % Circuito RLC: L*i'' + R*i' + (1/C)*i = V(t), ex: V(t)=0
                    strF = 'v';
                    strG = '-3*v - 12*u'; % Exemplo: L=1, R=3, C=1/12
                    a = 0; b = 5; n = 100; u0 = 0; v0 = 1;
                    app.PodeCalcularExata = true;

                case '4' % Pêndulo: u'' + (g/L)*sen(u) = 0, ex: g=9.8, L=2
                    strF = 'v';
                    strG = '-(9.8/2)*sin(u)';
                    a = 0; b = 10; n = 100; u0 = pi/4; v0 = 0;
                    app.PodeCalcularExata = false;

                case '5' % Oscilador simples: u'' + 4u = 0
                    strF = 'v';
                    strG = '-4*u';
                    a = 0; b = 10; n = 100; u0 = 1; v0 = 0;
                    app.PodeCalcularExata = true;

                case '6' % Sistema amortecido simples: u'' + u' + u = 0
                    strF = 'v';
                    strG = '-v - u';
                    a = 0; b = 10; n = 100; u0 = 1; v0 = 0;
                    app.PodeCalcularExata = true;
                otherwise
                    strF = 'v';
                    strG = '-16*u';
                    a = 0; b = 8; n = 100; u0 = 9; v0 = 0;
                    app.PodeCalcularExata = true;
            end

            app.eF.Value = strF;
            app.eG.Value = strG;
            app.eA.Value = a;
            app.eB.Value = b;
            app.nSpinner.Value = n;
            app.eU0.Value = u0;
            app.eV0.Value = v0;

            ExecutarButtonPushed(app, event)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {474, 474};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {224, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.RelatrioMenu = uimenu(app.FileMenu);
            app.RelatrioMenu.MenuSelectedFcn = createCallbackFcn(app, @RelatrioMenuSelected, true);
            app.RelatrioMenu.Text = 'Relatório';
            app.ExcelMenu = uimenu(app.FileMenu);
            app.ExcelMenu.MenuSelectedFcn = createCallbackFcn(app, @ExcelMenuSelected, true);
            app.ExcelMenu.Text = 'Excel';
            app.SairMenu = uimenu(app.FileMenu);
            app.SairMenu.MenuSelectedFcn = createCallbackFcn(app, @SairMenuSelected, true);
            app.SairMenu.Text = 'Sair';
            app.SobreMenu = uimenu(app.UIFigure);
            app.SobreMenu.Text = 'Sobre';
            app.AutoresMenu = uimenu(app.SobreMenu);
            app.AutoresMenu.MenuSelectedFcn = createCallbackFcn(app, @AutoresMenuSelected, true);
            app.AutoresMenu.Text = 'Autores';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DadosdoPVIPanel = uipanel(app.LeftPanel);
            app.DadosdoPVIPanel.Title = 'Dados do PVI';
            app.ftuvLabel = uilabel(app.DadosdoPVIPanel);
            app.ftuvLabel.Text = 'f(t,u,v)  =  ';
            app.eF = uieditfield(app.DadosdoPVIPanel, 'text');
            app.eF.Editable = 'off';
            app.eF.Value = 'v';
            app.aEditFieldLabel = uilabel(app.DadosdoPVIPanel);
            app.aEditFieldLabel.Text = 'a';
            app.eA = uieditfield(app.DadosdoPVIPanel, 'numeric');
            app.aEditFieldLabel_2 = uilabel(app.DadosdoPVIPanel);
            app.aEditFieldLabel_2.Text = 'b';
            app.eB = uieditfield(app.DadosdoPVIPanel, 'numeric');
            app.eB.Value = 8;
            app.aEditFieldLabel_3 = uilabel(app.DadosdoPVIPanel);
            app.aEditFieldLabel_3.Text = 'u0 =';
            app.eU0 = uieditfield(app.DadosdoPVIPanel, 'numeric');
            app.eU0.Value = 9;
            app.ftuvLabel_2 = uilabel(app.DadosdoPVIPanel);
            app.ftuvLabel_2.Text = 'g(t,u,v) =  ';
            app.eG = uieditfield(app.DadosdoPVIPanel, 'text');
            app.eG.Value = '-16*u';
            app.aEditFieldLabel_4 = uilabel(app.DadosdoPVIPanel);
            app.aEditFieldLabel_4.Text = 'v0 =';
            app.eV0 = uieditfield(app.DadosdoPVIPanel, 'numeric');
            app.nSpinnerLabel = uilabel(app.DadosdoPVIPanel);
            app.nSpinnerLabel.Text = 'n';
            app.nSpinner = uispinner(app.DadosdoPVIPanel);
            app.nSpinner.Limits = [1 Inf];
            app.nSpinner.ValueDisplayFormat = '%.0f';
            app.nSpinner.ValueChangedFcn = createCallbackFcn(app, @ExecutarButtonPushed, true);
            app.nSpinner.Value = 100;
            app.bgMNumericos = uibuttongroup(app.LeftPanel);
            app.bgMNumericos.SelectionChangedFcn = createCallbackFcn(app, @ExecutarButtonPushed, true);
            app.bgMNumericos.Title = 'Métodos Numéricos';
            app.EulerButton = uitogglebutton(app.bgMNumericos);
            app.EulerButton.Text = 'Euler';
            app.EulerMButton = uitogglebutton(app.bgMNumericos);
            app.EulerMButton.Text = 'EulerM';
            app.RK2Button = uitogglebutton(app.bgMNumericos);
            app.RK2Button.Text = 'RK2';
            app.RK2Button.Value = true;
            app.RK4Button = uitogglebutton(app.bgMNumericos);
            app.RK4Button.Text = 'RK4';
            app.ODE45Button = uitogglebutton(app.bgMNumericos);
            app.ODE45Button.Text = 'ODE45';
            app.RK3Button = uitogglebutton(app.bgMNumericos);
            app.RK3Button.Text = 'RK3';
            app.TodososMetodosButton = uitogglebutton(app.bgMNumericos);
            app.TodososMetodosButton.Text = 'Todos os Métodos';
            app.ExecutarButton = uibutton(app.LeftPanel, 'push');
            app.ExecutarButton.ButtonPushedFcn = createCallbackFcn(app, @ExecutarButtonPushed, true);
            app.ExecutarButton.Text = 'Executar';
            app.ResetButton = uibutton(app.LeftPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.GrelhaCheckBox = uicheckbox(app.LeftPanel);
            app.GrelhaCheckBox.ValueChangedFcn = createCallbackFcn(app, @GrelhaCheckBoxValueChanged, true);
            app.GrelhaCheckBox.Text = 'Grelha';
            app.SistemaDinmicoDropDownLabel = uilabel(app.LeftPanel);
            app.SistemaDinmicoDropDownLabel.Text = 'Sistema Dinâmico:';
            app.SistemaDinamicoDropDown = uidropdown(app.LeftPanel);
            app.SistemaDinamicoDropDown.Items = {'Mola-Massa s/ Amortecimento', 'Mola-Massa  c/ Amortecimento', 'Circuito Elétrico', 'Pêndulo', 'Oscilador Simples (Ext.)', ' Sistema amortecido simples (Ext.)'};
            app.SistemaDinamicoDropDown.ItemsData = {'1', '2', '3', '4', '5', '6'};
            app.SistemaDinamicoDropDown.ValueChangedFcn = createCallbackFcn(app, @SistemaDinamicoDropDownValueChanged, true);
            app.SistemaDinamicoDropDown.Value = '1';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.UIAxes = uiaxes(app.GridLayout2);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UITable = uitable(app.GridLayout2);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appMaquina01MNSED

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end