classdef appMaquina03CDI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        zfxyTab                       matlab.ui.container.Tab
        Panel                         matlab.ui.container.Panel
        Panel_3                       matlab.ui.container.Panel
        LabelHarmonica                matlab.ui.control.Label
        LabelEqLaplace                matlab.ui.control.Label
        LabelDParcial                 matlab.ui.control.Label
        ResetButton                   matlab.ui.control.Button
        ExecutarButton                matlab.ui.control.Button
        yLabel                        matlab.ui.control.Label
        eC                            matlab.ui.control.NumericEditField
        cLabel                        matlab.ui.control.Label
        Label_4                       matlab.ui.control.Label
        aEditFieldLabel_5             matlab.ui.control.Label
        eH2                           matlab.ui.control.NumericEditField
        aEditFieldLabel_4             matlab.ui.control.Label
        Label_3                       matlab.ui.control.Label
        eD                            matlab.ui.control.NumericEditField
        Label_2                       matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        xLabel                        matlab.ui.control.Label
        eB                            matlab.ui.control.NumericEditField
        aEditFieldLabel_3             matlab.ui.control.Label
        eH1                           matlab.ui.control.NumericEditField
        aEditFieldLabel_2             matlab.ui.control.Label
        eA                            matlab.ui.control.NumericEditField
        aEditFieldLabel               matlab.ui.control.Label
        eFxy                          matlab.ui.control.EditField
        fxyEditFieldLabel             matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
        yfxTab                        matlab.ui.container.Tab
        Panel_2                       matlab.ui.container.Panel
        DerivaoNumricaButtonGroup     matlab.ui.container.ButtonGroup
        TodasButton                   matlab.ui.control.RadioButton
        Derivada3pontosButton         matlab.ui.control.RadioButton
        Centradas3pontosButton        matlab.ui.control.RadioButton
        Regressivas3pontosButton      matlab.ui.control.RadioButton
        Progressivas3pontosButton     matlab.ui.control.RadioButton
        Regressivas2pontosButton      matlab.ui.control.RadioButton
        DFProgressivasButton          matlab.ui.control.RadioButton
        ResetButton_2                 matlab.ui.control.Button
        ExecutarButton_2              matlab.ui.control.Button
        Label_6                       matlab.ui.control.Label
        Label_5                       matlab.ui.control.Label
        xLabel_2                      matlab.ui.control.Label
        eB_2                          matlab.ui.control.NumericEditField
        aEditFieldLabel_8             matlab.ui.control.Label
        eH1_2                         matlab.ui.control.NumericEditField
        aEditFieldLabel_7             matlab.ui.control.Label
        eA_2                          matlab.ui.control.NumericEditField
        aEditFieldLabel_6             matlab.ui.control.Label
        eFx_2                         matlab.ui.control.EditField
        fxLabel                       matlab.ui.control.Label
        UIAxes_2                      matlab.ui.control.UIAxes
        IntegNumTab                   matlab.ui.container.Tab
        ResultadoPanel_IntNum         matlab.ui.container.Panel
        ErroEditField                 matlab.ui.control.NumericEditField
        ErroEditFieldLabel            matlab.ui.control.Label
        AproxEditField                matlab.ui.control.NumericEditField
        AproxEditFieldLabel           matlab.ui.control.Label
        ExatoEditField                matlab.ui.control.NumericEditField
        ExatoEditFieldLabel           matlab.ui.control.Label
        Panel_4                       matlab.ui.container.Panel
        minButGroup                   matlab.ui.container.ButtonGroup
        RSimpsonButton                matlab.ui.control.RadioButton
        RTrapziosButton               matlab.ui.control.RadioButton
        NmerodeSubintervalosPanel     matlab.ui.container.ButtonGroup
        nEditField_SubInt             matlab.ui.control.NumericEditField
        nEditFieldLabel               matlab.ui.control.Label
        ResetButton_IntNum            matlab.ui.control.Button
        ExecutarButton_IntNum         matlab.ui.control.Button
        Label_7                       matlab.ui.control.Label
        bField                        matlab.ui.control.NumericEditField
        bLabel                        matlab.ui.control.Label
        aField                        matlab.ui.control.NumericEditField
        aEditFieldLabel_9             matlab.ui.control.Label
        eFx_intNum                    matlab.ui.control.EditField
        fxLabel_2                     matlab.ui.control.Label
        UIAxes_3                      matlab.ui.control.UIAxes
        CoordenadasTab                matlab.ui.container.Tab
        Panel_6                       matlab.ui.container.Panel
        ThetaEditField                matlab.ui.control.EditField
        ThetaEditFieldLabel           matlab.ui.control.Label
        zEditField_2                  matlab.ui.control.EditField
        zEditField_2Label             matlab.ui.control.Label
        RhoEditField                  matlab.ui.control.EditField
        RhoEditFieldLabel             matlab.ui.control.Label
        Label_13                      matlab.ui.control.Label
        Label_12                      matlab.ui.control.Label
        Label_11                      matlab.ui.control.Label
        ConversoButtonGroup_2         matlab.ui.container.ButtonGroup
        EsfricaparaCartesianaButton   matlab.ui.control.RadioButton
        CilindroparaCartesianaButton  matlab.ui.control.RadioButton
        PolarparaCartesianaButton     matlab.ui.control.RadioButton
        ResetButton_4                 matlab.ui.control.Button
        ConverterButton_2             matlab.ui.control.Button
        Panel_5                       matlab.ui.container.Panel
        xEditField                    matlab.ui.control.EditField
        xEditFieldLabel               matlab.ui.control.Label
        zEditField                    matlab.ui.control.EditField
        zEditFieldLabel               matlab.ui.control.Label
        yEditField                    matlab.ui.control.EditField
        yEditFieldLabel               matlab.ui.control.Label
        Label_10                      matlab.ui.control.Label
        Label_9                       matlab.ui.control.Label
        Label_8                       matlab.ui.control.Label
        ConversoButtonGroup           matlab.ui.container.ButtonGroup
        CartesianaparaEsfricaButton   matlab.ui.control.RadioButton
        CartesianaparaCilindroButton  matlab.ui.control.RadioButton
        CartesianaparaPolarButton     matlab.ui.control.RadioButton
        ResetButton_3                 matlab.ui.control.Button
        ConverterButton               matlab.ui.control.Button
        AIHelperTab                   matlab.ui.container.Tab
        TabSelecionadaDropDown        matlab.ui.control.DropDown
        TabSelecionadaDropDownLabel   matlab.ui.control.Label
        eResposta                     matlab.ui.control.TextArea
        RespostaTextAreaLabel         matlab.ui.control.Label
        ePergunta                     matlab.ui.control.TextArea
        PerguntaTextAreaLabel         matlab.ui.control.Label
        btEnviar                      matlab.ui.control.Button
    end

    
    methods (Access = public)
        function [S, exactValue, errorValue] = Fregra_simpson(~, f, a, b, n)
        % REGRA_SIMPSON_SIMBOLICO
        %   Integração pela regra de Simpson + valor exato simbólico e erro.
        %
        % Entradas:
        %   f    – função (handle), ex: @(x) x.^2
        %   a,b  – limites de integração
        %   n    – número de subintervalos (deve ser par)
        %
        % Saídas:
        %   S          – aproximação pela regra de Simpson
        %   exactValue – valor exato (numérico) obtido de expr
        %   errorValue – erro absoluto |exactValue – S|
        
            h = (b - a) / n;
            sumOdd  = 0;
            sumEven = 0;
            for i = 1:(n-1)
                xi = a + i*h;
                if mod(i,2) == 0
                    sumEven = sumEven + f(xi);
                else
                    sumOdd  = sumOdd  + f(xi);
                end
            end
            S = (h/3) * ( f(a) + 4*sumOdd + 2*sumEven + f(b) );
        
            % 2) cálculo simbólico “exato”
            syms x
            funcaoTransformada = symfun( f(x), x );
            expressao  = int(funcaoTransformada, x, a, b);
            exactValue = double(expressao);
        
            % 3) erro absoluto
            errorValue = abs(exactValue - S);
        end

        function [T, exactValue, errorValue] = Fregra_trapezios(~, f, a, b, n)
        % REGRA_TRAPEZIOS_SIMBOLICO
        %   Integração pela regra dos trapézios + valor exato simbólico e erro.
        %
        % Entradas:
        %   f    – função (handle), ex: @(x) x.^2
        %   a,b  – limites de integração
        %   n    – número de subintervalos
        %
        % Saídas:
        %   T          – aproximação pela regra dos trapézios
        %   exactValue – valor exato (numérico) obtido de expr
        %   errorValue – erro absoluto |exactValue – T|
            
            h = (b - a)/n;
            s = sum( f(a + (1:n-1)*h) );
            T = (h/2)*( f(a) + 2*s + f(b) );
             
            syms x;
            funcaoTransformada = symfun( f(x), x );
            expressao = int(funcaoTransformada, x, a, b);
            exactValue = double(expressao);
             
            errorValue = abs(exactValue - T);
        end

        
        function [x,y,dydx] = NDerivacaoDFP(~,f,a,b,h,y)
            x = a:h:b;
            n = length(x);
            if nargin == 5
                y = f(x);
            end
            dydx = zeros(1,n);
            for k = 1:n-1
                dydx(k) = (y(k+1)-y(k))/h;
            end
            dydx(n) = (y(n)-y(n-1))/h;
            %dydx(n) = dydx(n-1);
            
        end
        
        function YN = isHarmonica(~,f)
            syms z(x,y)
            if simplify(diff(f,x,2)+diff(diff(f,y),y))==0
                YN = 1;
            else YN = 0;
            end           
        end

        function [x,y] = evalFunc(~,f, a, b)
             x = linspace(a, b, 1000);
             y = arrayfun(f, x);
        end
        
        function [x,y,dydx] = NDerivacaoDFR(~,f,a,b,h,y) %DFR 2 pontos
            x=a:h:b;                                % Alocação de memória
            n=length(x);                            % Número de pontos (tamanho do vetor de abcissas)
            
            if nargin==5
                y=f(x);                             % y é a função f(x)
            end
            
            dydx=zeros(1,n);                        % Alocação de memória
            
            dydx(1)=(y(2)-y(1))/h;                  % Derivada (aproximada) de f no ponto atual
            
            for i=2:n
                dydx(i)=(y(i)-y(i-1))/h;            % Derivada (aproximada) de f no ponto atual
            end
        end

        function [x,y,dydx] = NDerivacaoDFP3(~,f,a,b,h,y)
            x=a:h:b;                                                    % Alocação de memória
            n=length(x);                                                % Número de pontos (tamanho do vetor de abcissas)
            
            if nargin==5
                y=f(x);                                                 % y é a função f(x)
            end
            
            dydx=zeros(1,n);                                            % Alocação de memória
            
            for i=1:n-2
                dydx(i)=(-3*y(i) + 4*y(i+1) - y(i+2))/(2*h);            % Derivada (aproximada) de f no ponto atual
            end
            
            dydx(n-1)=(y(n-3) - 4*y(n-2) + 3*y(n-1))/(2*h);             % Derivada (aproximada) de f no ponto atual
            dydx(n)=(y(n-2) - 4*y(n-1) + 3*y(n))/(2*h);                 % Derivada (aproximada) de f no ponto atual
        end

        function [x,y,dydx] = NDerivacaoDFR3(~,f,a,b,h,y)

            x=a:h:b;                                            % Alocação de memória
            n=length(x);                                        % Número de pontos (tamanho do vetor de abcissas)
            
            if nargin==5
                y=f(x);                                         % y é a função f(x)
            end
            
            dydx=zeros(1,n);                                    % Alocação de memória
            
            dydx(1)=(-3*y(1) + 4*y(2) - y(3))/(2*h);            % Derivada (aproximada) de f no ponto atual
            dydx(2)=(-3*y(2) + 4*y(3) - y(4))/(2*h);            % Derivada (aproximada) de f no ponto atual
            
            for i=3:n
                dydx(i)=(y(i-2) - 4*y(i-1) + 3*y(i))/(2*h);     % Derivada (aproximada) de f no ponto atual
    
            end            
        end

        function [x,y,dydx] = NDerivacaoDFC(~,f,a,b,h,y)
            
            x=a:h:b;                                                % Alocação de memória
            n=length(x);                                            % Número de pontos (tamanho do vetor de abcissas)
            
            if nargin==5
                y=f(x);                                             % y é a função f(x)
            end
            
            dydx=zeros(1,n);                                        % Alocação de memória
            
            dydx(1)=(-3*y(1) + 4*y(2) - y(3))/(2*h);                % Derivada (aproximada) de f no ponto atual
            
            for i=2:n-1
                dydx(i)=(y(i+1)-y(i-1))/(2*h);                      % Derivada (aproximada) de f no ponto atual
            end
            
            dydx(n)=(y(n-2) - 4*y(n-1) + 3*y(n))/(2*h);             % Derivada (aproximada) de f no ponto atual
        end

        function [x,y,dydx] = NDerivacaoD2(~,f,a,b,h,y)
            x=a:h:b;                                                    % Alocação de memória
            n=length(x);                                                % Número de pontos (tamanho do vetor de abcissas)
            
            if nargin==5
                y=f(x);                                                 % y é a função f(x)
            end
            
            dydx=zeros(1,n);                                            % Alocação de memória
            
            temp1=(-3*y(1) + 4*y(2) - y(3))/(2*h);                      % Primeira derivada no ponto x = a
            temp2=(-3*y(2) + 4*y(3) - y(4))/(2*h);                      % Primeira derivada no ponto x = a + h
            temp3=(-3*y(3) + 4*y(4) - y(5))/(2*h);                      % Primeira derivada no ponto x = a + 2*h
            
            dydx(1)=(-3*temp1 + 4*temp2 - temp3)/(2*h);                 % Segunda derivada no ponto x = a
            
            for i=2:n-1
                dydx(i)=(y(i+1) - 2*y(i) + y(i-1))/(h*h);               % Derivada (aproximada) de f no ponto atual
            end
            
            tempn2=(y(n-4) - 4*y(n-3) + 3*y(n-2))/(2*h);                % Primeira derivada no ponto x = b - 2*h
            tempn1=(y(n-3) - 4*y(n-2) + 3*y(n-1))/(2*h);                % Primeira derivada no ponto x = b - h
            tempn=(y(n-2) - 4*y(n-1) + 3*y(n))/(2*h);                   % Primeira derivada no ponto x = b
            
            dydx(n)=(tempn2 - 4*tempn1 + 3*tempn)/(2*h);                % Segunda derivada no ponto x = b
        end
    end
    
    methods (Access = private)
        
        function cleanedText = cleanText(~, text)
            % Converter para string
            texto = string(text);
            
            % Remover Markdown
            texto = regexprep(texto, '\*\*([^\*]+)\*\*', '$1'); % Negrito (**texto**)
            texto = regexprep(texto, '\*([^\*]+)\*', '$1');     % Itálico (*texto*)
            texto = regexprep(texto, '\#+\s*', '');             % Cabeçalhos (#, ##, etc.)
            texto = regexprep(texto, '\-\s*', '');              % Listas (- item)
            texto = regexprep(texto, '\>\s*', '');              % Citações (> texto)
            texto = regexprep(texto, '\`([^\`]+)\`', '$1');     % Código inline (`texto`)
            texto = regexprep(texto, '\`\`\`[\s\S]*?\`\*\*', ''); % Blocos de código (```texto```)
            
            
            cleanedText = char(texto);
        end
        
        function results = aiHider(app)
            
            pergunta = string(app.ePergunta.Value);
            prompt = '';
            
            dropDown = app.TabSelecionadaDropDown.Value;
            
            switch dropDown
                case "z = f(x,y)"
                    func = string(app.eFxy.Value);
                    xA = string(app.eA.Value);
                    xB = string(app.eB.Value);
                    xIntervalo = string(app.eH1.Value);
                    yC = string(app.eC.Value);
                    yD = string(app.eD.Value);
                    yIntervalo = string(app.eH2.Value);
                    harmonica = string(app.LabelHarmonica.Text);
                    
                    isHarmonicaCode = {
                        'function YN = isHarmonica(~, f)', ...
                        'syms z(x,y)', ...
                        'if simplify(diff(f,x,2) + diff(diff(f,y),y)) == 0', ...
                        'YN = 1;', ...
                        'else', ...
                        'YN = 0;', ...
                        'end'
                    };
                    
                    %disp('isHarmonicaCode antes de strjoin:');
                    %for i = 1:length(isHarmonicaCode)
                    %    disp([num2str(i), ': ', isHarmonicaCode{i}]);
                    %end
                    
                    isHarmonicaCode = strrep(strjoin(isHarmonicaCode, ' '), '\n', '\\n');
                    
                    %disp(['isHarmonicaCode após strjoin: ', isHarmonicaCode]);
                    
                    contexto = sprintf('Contexto: Estou a verificar se a função z = %s é harmônica no intervalo x=[%s, %s] com passo %s e y=[%s, %s] com passo %s. O resultado é: %s.\n', ...
                        func, xA, xB, xIntervalo, yC, yD, yIntervalo, harmonica);
                    codigo = sprintf('A função MATLAB usada para verificar harmonicidade é: %s\n\n', isHarmonicaCode);
                    pergunta_part = sprintf('Pergunta: %s\n', pergunta);
                    instrucao = 'Responde sempre em Português de Portugal, em texto simples, sem Markdown, negrito, itálico, listas ou qualquer formatação. Use espaços adequados entre palavras e pontuação para garantir legibilidade. Evite compactar palavras, assegurando que cada palavra esteja separada por um espaço.';
                    prompt = strcat(contexto, codigo, pergunta_part, instrucao);
                    
                case "y = f(x)"
                    func = string(app.eFx_2.Value);
                    xA = string(app.eA_2.Value);
                    xB = string(app.eB_2.Value);
                    xIntervalo = string(app.eH1_2.Value);
                    derivacao = string(app.DerivaoNumricaButtonGroup.SelectedObject.Text);
                    
                    switch derivacao
                        case 'DFProgressivas'
                            derivCode = {
                                'function [x,y,dydx] = NDerivacaoDFP(~,f,a,b,h,y)', ...
                                'x = a:h:b;', ...
                                'n = length(x);', ...
                                'if nargin == 5', ...
                                'y = f(x);', ...
                                'end', ...
                                'dydx = zeros(1,n);', ...
                                'for k = 1:n-1', ...
                                'dydx(k) = (y(k+1)-y(k))/h;', ...
                                'end', ...
                                'dydx(n) = (y(n)-y(n-1))/h;', ...
                                'end'
                            };
                            metodo = 'f''(x_k) ≈ (f(x_{k+1}) - f(x_k))/h';
                        case 'Regressivas (2 pontos)'
                            derivCode = {
                                'function [x,y,dydx] = NDerivacaoDFR(~,f,a,b,h,y)', ...
                                'x = a:h:b;', ...
                                'n = length(x);', ...
                                'if nargin == 5', ...
                                'y = f(x);', ...
                                'end', ...
                                'dydx = zeros(1,n);', ...
                                'dydx(1) = (y(2)-y(1))/h;', ...
                                'for i = 2:n', ...
                                'dydx(i) = (y(i)-y(i-1))/h;', ...
                                'end', ...
                                'end'
                            };
                            metodo = 'f''(x_k) ≈ (f(x_k) - f(x_{k-1}))/h';
                        case 'Progressivas (3 pontos)'
                            derivCode = {
                                'function [x,y,dydx] = NDerivacaoDFP3(~,f,a,b,h,y)', ...
                                'x = a:h:b;', ...
                                'n = length(x);', ...
                                'if nargin == 5', ...
                                'y = f(x);', ...
                                'end', ...
                                'dydx = zeros(1,n);', ...
                                'for i = 1:n-2', ...
                                'dydx(i) = (-3*y(i) + 4*y(i+1) - y(i+2))/(2*h);', ...
                                'end', ...
                                'dydx(n-1) = (y(n-3) - 4*y(n-2) + 3*y(n-1))/(2*h);', ...
                                'dydx(n) = (y(n-2) - 4*y(n-1) + 3*y(n))/(2*h);', ...
                                'end'
                            };
                            metodo = 'f''(x_k) ≈ (-3f(x_k) + 4f(x_{k+1}) - f(x_{k+2}))/2h';
                        case 'Regressivas (3 pontos)'
                            derivCode = {
                                'function [x,y,dydx] = NDerivacaoDFR3(~,f,a,b,h,y)', ...
                                'x = a:h:b;', ...
                                'n = length(x);', ...
                                'if nargin == 5', ...
                                'y = f(x);', ...
                                'end', ...
                                'dydx = zeros(1,n);', ...
                                'dydx(1) = (-3*y(1) + 4*y(2) - y(3))/(2*h);', ...
                                'dydx(2) = (-3*y(2) + 4*y(3) - y(4))/(2*h);', ...
                                'for i = 3:n', ...
                                'dydx(i) = (y(i-2) - 4*y(i-1) + 3*y(i))/(2*h);', ...
                                'end', ...
                                'end'
                            };
                            metodo = 'f''(x_k) ≈ (f(x_{k-2}) - 4f(x_{k-1}) + 3f(x_k))/2h';
                        case 'Centradas (3 pontos)'
                            derivCode = {
                                'function [x,y,dydx] = NDerivacaoDFC(~,f,a,b,h,y)', ...
                                'x = a:h:b;', ...
                                'n = length(x);', ...
                                'if nargin == 5', ...
                                'y = f(x);', ...
                                'end', ...
                                'dydx = zeros(1,n);', ...
                                'dydx(1) = (-3*y(1) + 4*y(2) - y(3))/(2*h);', ...
                                'for i = 2:n-1', ...
                                'dydx(i) = (y(i+1) - y(i-1))/(2*h);', ...
                                'end', ...
                                'dydx(n) = (y(n-2) - 4*y(n-1) + 3*y(n))/(2*h);', ...
                                'end'
                            };
                            metodo = 'f''(x_k) ≈ (f(x_{k+1}) - f(x_{k-1}))/2h';
                        case '2ª Derivada (3 pontos)'
                            derivCode = {
                                'function [x,y,dydx] = NDerivacaoD2(~,f,a,b,h,y)', ...
                                'x = a:h:b;', ...
                                'n = length(x);', ...
                                'if nargin == 5', ...
                                'y = f(x);', ...
                                'end', ...
                                'dydx = zeros(1,n);', ...
                                'temp1 = (-3*y(1) + 4*y(2) - y(3))/(2*h);', ...
                                'temp2 = (-3*y(2) + 4*y(3) - y(4))/(2*h);', ...
                                'temp3 = (-3*y(3) + 4*y(4) - y(5))/(2*h);', ...
                                'dydx(1) = (-3*temp1 + 4*temp2 - temp3)/(2*h);', ...
                                'for i = 2:n-1', ...
                                'dydx(i) = (y(i+1) - 2*y(i) + y(i-1))/(h*h);', ...
                                'end', ...
                                'tempn2 = (y(n-4) - 4*y(n-3) + 3*y(n-2))/(2*h);', ...
                                'tempn1 = (y(n-3) - 4*y(n-2) + 3*y(n-1))/(2*h);', ...
                                'tempn = (y(n-2) - 4*y(n-1) + 3*y(n))/(2*h);', ...
                                'dydx(n) = (tempn2 - 4*tempn1 + 3*tempn)/(2*h);', ...
                                'end'
                            };
                            metodo = 'f''''(x_k) ≈ (f(x_{k+1}) - 2f(x_k) + f(x_{k-1}))/h^2';
                        otherwise
                            derivCode = {'Nenhum código disponível'};
                            metodo = derivacao;
                    end
                    derivCode = strrep(strjoin(derivCode, ' '), '\n', '\\n');
                    
                    %disp('Argumentos para prompt em y = f(x):');
                    %disp(['func: ', char(func)]);
                    %disp(['xA: ', char(xA)]);
                    %disp(['xB: ', char(xB)]);
                    %disp(['xIntervalo: ', char(xIntervalo)]);
                    %disp(['derivacao: ', char(derivacao)]);
                    %disp(['metodo: ', metodo]);
                    %disp(['derivCode: ', derivCode]);
                    %disp(['pergunta: ', char(pergunta)]);
                    
                    contexto = sprintf('Contexto: Estou a calcular a derivada numérica de y = %s no intervalo x=[%s, %s] com passo %s, usando o método %s.\n', ...
                        func, xA, xB, xIntervalo, derivacao);
                    formula = sprintf('Fórmula do método: %s\n', metodo);
                    codigo = sprintf('A função MATLAB usada é: %s\n\n', derivCode);
                    pergunta_part = sprintf('Pergunta: %s\n', pergunta);
                    instrucao = 'Responde sempre em Português de Portugal, em texto simples, sem Markdown, negrito, itálico, listas ou qualquer formatação. Use espaços adequados entre palavras e pontuação para garantir legibilidade. Evite compactar palavras, assegurando que cada palavra esteja separada por um espaço.';
                    prompt = strcat(contexto, formula, codigo, pergunta_part, instrucao);
                    
                case "IntegNum"
                    func = string(app.eFx_intNum.Value);
                    xA = string(app.aField.Value);
                    xB = string(app.bField.Value);
                    nIntervalos = string(app.nEditField_SubInt.Value);
                    metIntegracao = string(app.minButGroup.SelectedObject.Text);
                    vErro = string(app.ErroEditField.Value);
                    vExato = string(app.ExatoEditField.Value);
                    vAprox = string(app.AproxEditField.Value);
                    
                    if strcmp(metIntegracao, 'R Trapézios')
                        integCode = {
                            'function [T, exactValue, errorValue] = Fregra_trapezios(~, f, a, b, n)', ...
                            'h = (b - a)/n;', ...
                            's = sum(f(a + (1:n-1)*h));', ...
                            'T = (h/2)*(f(a) + 2*s + f(b));', ...
                            'syms x', ...
                            'funcaoTransformada = symfun(f(x), x);', ...
                            'expressao = int(funcaoTransformada, x, a, b);', ...
                            'exactValue = double(expressao);', ...
                            'errorValue = abs(exactValue - T);', ...
                            'end'
                        };
                        formula = 'I_T(f) = (h/2) [f(x_0) + 2*sum(f(x_i)) + f(x_n)]';
                    else % Simpson
                        integCode = {
                            'function [S, exactValue, errorValue] = Fregra_simpson(~, f, a, b, n)', ...
                            'h = (b - a)/n;', ...
                            'sumOdd = 0;', ...
                            'sumEven = 0;', ...
                            'for i = 1:n-1', ...
                            'xi = a + i*h;', ...
                            'if mod(i,2) == 0', ...
                            'sumEven = sumEven + f(xi);', ...
                            'else', ...
                            'sumOdd = sumOdd + f(xi);', ...
                            'end', ...
                            'end', ...
                            'S = (h/3)*(f(a) + 4*sumOdd + 2*sumEven + f(b));', ...
                            'syms x', ...
                            'funcaoTransformada = symfun(f(x), x);', ...
                            'expressao = int(funcaoTransformada, x, a, b);', ...
                            'exactValue = double(expressao);', ...
                            'errorValue = abs(exactValue - S);', ...
                            'end'
                        };
                        formula = 'I_S(f) = (h/3) [f(x_0) + 4*sum_odd(f(x_i)) + 2*sum_even(f(x_i)) + f(x_n)]';
                    end
                    integCode = strrep(strjoin(integCode, ' '), '\n', '\\n');
                    
                    contexto = sprintf('Contexto: Estou a calcular a integral numérica de f(x) = %s no intervalo [%s, %s] com %s subintervalos, usando o método %s.\n', ...
                        func, xA, xB, nIntervalos, metIntegracao);
                    formula_part = sprintf('Fórmula do método: %s\n', formula);
                    resultados = sprintf('Resultados: Valor aproximado = %s, Valor exato = %s, Erro = %s.\n', vAprox, vExato, vErro);
                    codigo = sprintf('A função MATLAB usada é: %s\n\n', integCode);
                    pergunta_part = sprintf('Pergunta: %s\n', pergunta);
                    instrucao = 'Responde sempre em Português de Portugal, em texto simples, sem Markdown, negrito, itálico, listas ou qualquer formatação. Use espaços adequados entre palavras e pontuação para garantir legibilidade. Evite compactar palavras, assegurando que cada palavra esteja separada por um espaço.';
                    prompt = strcat(contexto, formula_part, resultados, codigo, pergunta_part, instrucao);
                    
                case "Coordenadas"
                    x = string(app.xEditField.Value);
                    y = string(app.yEditField.Value);
                    z = string(app.zEditField.Value);
                    Conversao = string(app.ConversoButtonGroup.SelectedObject.Text);
                    
                    %disp('Argumentos para prompt em Coordenadas:');
                    %disp(['x: ', char(x)]);
                    %disp(['y: ', char(y)]);
                    %disp(['z: ', char(z)]);
                    %disp(['Conversao: ', char(Conversao)]);
                    %disp(['pergunta: ', char(pergunta)]);
                    
                    contexto = sprintf('Contexto: Estou a converter coordenadas com os valores x = %s, y = %s, z = %s, usando o tipo de conversão: %s.\n', ...
                        x, y, z, Conversao);
                    pergunta_part = sprintf('Pergunta: %s\n', pergunta);
                    instrucao = 'Responde sempre em Português de Portugal, em texto simples, sem Markdown, negrito, itálico, listas ou qualquer formatação. Use espaços adequados entre palavras e pontuação para garantir legibilidade. Evite compactar palavras, assegurando que cada palavra esteja separada por um espaço.';
                    prompt = strcat(contexto, pergunta_part, instrucao);
            end
            
            apiKey = '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';
            url = 'https://openrouter.ai/api/v1/chat/completions';
            
            data = struct( ...
                'model', 'deepseek/deepseek-r1-0528:free', ...
                'messages', {{struct('role', 'user', 'content', prompt)}}, ...
                'temperature', 0.85 ...
            );
            
            headers = { ...
                'Content-Type', 'application/json'; ...
                'Authorization', ['Bearer ' apiKey] ...
            };
            
            options = weboptions( ...
                'HeaderFields', headers, ...
                'MediaType', 'application/json', ...
                'Timeout', 20 ...
            );
            
            try
                resposta = webwrite(url, data, options);
                if iscell(resposta.choices)
                    mensagem = resposta.choices{1}.message.content;
                else
                    mensagem = resposta.choices(1).message.content;
                end

                %disp('Resposta da IA:');
                %disp(mensagem);
                %mensagem = cleanText(app,mensagem);
                
                app.eResposta.Value = mensagem;
            catch erro
                app.eResposta.Value = ['Erro: ', erro.message];
            end
            
                       
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            ExecutarButtonPushed(app, [])
            ExecutarButton_2Pushed(app, [])
            ExecutarButton_IntNumPushed(app, [])
            ConverterButtonPushed(app, [])
            ConverterButton_2Pushed(app, [])
        end

        % Button pushed function: ExecutarButton
        function ExecutarButtonPushed(app, event)
            erroCounter = 0;
            erroString  = "";

            % Leitura dos campos
            strF = app.eFxy.Value;
            h1    = app.eH1.Value;
            h2    = app.eH2.Value;

            % Valida f(t,u,v)
            try
                f =@(x,y) eval(vectorize(strF));
                % avalia em ponto de teste
                f(0,1);
                app.eFxy.BackgroundColor = [1,1,1];
            catch
                app.eFxy.BackgroundColor = [1,0.8,0.8];
                erroCounter = erroCounter + 1;
                erroString = erroString + "Introduza uma função válida para f." + newline;
            end


            % Valida h1 e h2
            if ~isnumeric(h1) || h1 <= 0
                app.eH1.BackgroundColor = [1,0.8,0.8];
                erroCounter = erroCounter + 1;
                erroString = erroString + "h1 deve ser um número finito." + newline;
            else
                app.eH1.BackgroundColor = [1,1,1];
            end
            if ~isnumeric(h2) || h2 <= 0
                app.eH2.BackgroundColor = [1,0.8,0.8];
                erroCounter = erroCounter + 1;
                erroString = erroString + "h2 deve ser um número finito." + newline;
            else
                app.eH2.BackgroundColor = [1,1,1];
            end

            % Se houver erros, mostra diálogo e sai
            if erroCounter > 0
                errordlg(erroString, "Erro de Validação", "modal");
                return;
            end
            
            syms z(x,y)
            strF = app.eFxy.Value;
            f =@(x,y) eval(vectorize(strF));
            a = app.eA.Value; h1 = app.eH1.Value; b = app.eB.Value;
            c = app.eC.Value; h2 = app.eH2.Value; d = app.eD.Value;
            
            erroFxy = MException("MATLAB:executar:badFxy",...
                                 "Introduza uma função real em x e y");

            try
                try
                    z = f(x,y);
                    app.eFxy.BackgroundColor = "w";
                catch
                    app.eFxy.BackgroundColor = "r";
                    throw(erroFxy)
                end

                dfdxsym = diff(f,x);
                LatexDParcial = ['$',...
                   '\displaystyle \frac{\partial z}{\partial x}=',...
                   latex(dfdxsym),...
                   '$'];
                app.LabelDParcial.Text = LatexDParcial;

                LatexEqLaplace = ['$',...
                   '\displaystyle \frac{\partial^2 f}{\partial x^2}+\frac{\partial^2 f}{\partial y^2}=0',...
                   '$'];
                app.LabelEqLaplace.Text = LatexEqLaplace;

                if isHarmonica(app,f)
                    textoHarmonica = "A função é harmónica";
                    corHarmonica = "g";
                else
                    textoHarmonica = "A função não é harmónica";
                    corHarmonica = "r";
                end
                app.LabelHarmonica.Text = textoHarmonica;
                app.LabelHarmonica.FontColor = corHarmonica;
                    
                [x,y] = meshgrid(a:h1:b,c:h2:d);
                z = f(x,y);
                %mesh(app.UIAxes,x,y,z)
                surf(app.UIAxes,x,y,z)
            catch Me
                errordlg(Me.message,"ERRO","modal")
            end
        end

        % Button pushed function: ExecutarButton_2
        function ExecutarButton_2Pushed(app, event)
            erroCounter = 0;
            erroString  = "";

            % Leitura dos campos
            strFx = app.eFx_2.Value;
            a = app.eA_2.Value;
            b    = app.eB_2.Value;
            h1    = app.eH1_2.Value;

            % Valida f(t,u,v)
            try
                f =@(x) eval(vectorize(strFx));
                % avalia em ponto de teste
                f(1);
                app.eFx_2.BackgroundColor = [1,1,1];
            catch
                app.eFx_2.BackgroundColor = [1,0.8,0.8];
                erroCounter = erroCounter + 1;
                erroString = erroString + "Introduza uma função válida para f." + newline;
            end

            % Valida intervalo [a,b]
            if ~isnumeric(a) || ~isnumeric(b) || isnan(a) || isnan(b)
                app.eA_2.BackgroundColor = [1,0.8,0.8];
                app.eB_2.BackgroundColor = [1,0.8,0.8];
                erroCounter = erroCounter + 1;
                erroString = erroString + "Os campos a e b devem ser números válidos." + newline;
            elseif b <= a
                app.eA_2.BackgroundColor = [1,0.8,0.8];
                app.eB_2.BackgroundColor = [1,0.8,0.8];
                erroCounter = erroCounter + 1;
                erroString = erroString + "Deve ser b > a no intervalo." + newline;
            else
                app.eA_2.BackgroundColor = [1,1,1];
                app.eB_2.BackgroundColor = [1,1,1];
            end


            % Valida h1 e h2
            if ~isnumeric(h1) || h1 <= 0
                app.eH1_2.BackgroundColor = [1,0.8,0.8];
                erroCounter = erroCounter + 1;
                erroString = erroString + "h1 deve ser um número finito." + newline;
            else
                app.eH1_2.BackgroundColor = [1,1,1];
            end

            % Se houver erros, mostra diálogo e sai
            if erroCounter > 0
                errordlg(erroString, "Erro de Validação", "modal");
                return;
            end
            
            syms y(x)
            strFx = app.eFx_2.Value;
            f =@(x) eval(vectorize(strFx));
            a = app.eA_2.Value; h = app.eH1_2.Value; b = app.eB_2.Value;
            selectecMethod = app.DerivaoNumricaButtonGroup.SelectedObject;

            try

                switch selectecMethod.Text
                    case "DFProgressivas"
                        
                        dydxsym = diff(f,x);
                        dfdx =@(x) eval(vectorize(char(dydxsym)));
                        [x,y,dydxDFP] = NDerivacaoDFP(app,f,a,b,h);
                        dydxExata = dfdx(x);
                        erroDFP = abs(dydxExata-dydxDFP);
                        plot(app.UIAxes_2,x,y,"-r*");
                        hold(app.UIAxes_2,"on");
                        plot(app.UIAxes_2,x,dydxExata,"-g+");
                        plot(app.UIAxes_2,x,dydxDFP,"-bo");
                        %plot(app.UIAxes_2,x,erroDFP,"-k");
                        hold(app.UIAxes_2,"off");
                        title(app.UIAxes_2, "DFProgressivas");
                        legendaGrafico = ["y=f(x)","dydxExata","dydxDFP"];
                        legend(app.UIAxes_2,legendaGrafico);
                        grid(app.UIAxes_2,"on");

                    case "Regressivas (2 pontos)"
                        
                        dydxsym = diff(f, x);
                        dfdx =@(x) eval(vectorize(char(dydxsym)));
                        [x,y,dydxDFR] = NDerivacaoDFR(app,f,a,b,h);
                        dydxExata = dfdx(x);
                        erroDFR = abs(dydxExata-dydxDFR);
                        plot(app.UIAxes_2,x,y,"-r*");
                        hold(app.UIAxes_2,"on");
                        plot(app.UIAxes_2,x,dydxExata,"-g+");
                        plot(app.UIAxes_2,x,dydxDFR,"-bo");
                        %plot(app.UIAxes_2,x,erroDFR,"-k");
                        hold(app.UIAxes_2,"off");
                        title(app.UIAxes_2, "Regressivas (2 pontos)");
                        legendaGrafico = ["y=f(x)","dydxExata","dydxDFR"];
                        legend(app.UIAxes_2,legendaGrafico);
                        grid(app.UIAxes_2,"on");

                    case "Progressivas (3 pontos)"

                        dydxsym = diff(f, x);
                        dfdx =@(x) eval(vectorize(char(dydxsym)));
                        [x,y,dydxDFP3] = NDerivacaoDFP3(app,f,a,b,h);
                        dydxExata = dfdx(x);
                        erroDFP3 = abs(dydxExata-dydxDFP3);
                        plot(app.UIAxes_2,x,y,"-r*");
                        hold(app.UIAxes_2,"on");
                        plot(app.UIAxes_2,x,dydxExata,"-g+");
                        plot(app.UIAxes_2,x,dydxDFP3,"-bo");
                        %plot(app.UIAxes_2,x,erroDFP3,"-k");
                        hold(app.UIAxes_2,"off");
                        title(app.UIAxes_2, "Progressivas (3 pontos)");
                        legendaGrafico = ["y=f(x)","dydxExata","dydxDFP3"];
                        legend(app.UIAxes_2,legendaGrafico);
                        grid(app.UIAxes_2,"on");

                    case "Regressivas (3 pontos)"

                        dydxsym = diff(f, x);
                        dfdx =@(x) eval(vectorize(char(dydxsym)));
                        [x,y,dydxDFR3] = NDerivacaoDFR3(app,f,a,b,h);
                        dydxExata = dfdx(x);
                        erroDFR3 = abs(dydxExata-dydxDFR3);
                        plot(app.UIAxes_2,x,y,"-r*");
                        hold(app.UIAxes_2,"on");
                        plot(app.UIAxes_2,x,dydxExata,"-g+");
                        plot(app.UIAxes_2,x,dydxDFR3,"-bo");
                        %plot(app.UIAxes_2,x,erroDFR3,"-k");
                        hold(app.UIAxes_2,"off");
                        title(app.UIAxes_2, "Regressivas (3 pontos)");
                        legendaGrafico = ["y=f(x)","dydxExata","dydxDFR3"];
                        legend(app.UIAxes_2,legendaGrafico);
                        grid(app.UIAxes_2,"on");
                    
                    case "Centradas (3 pontos)"

                        dydxsym = diff(f, x);
                        dfdx =@(x) eval(vectorize(char(dydxsym)));
                        [x,y,dydxDFC] = NDerivacaoDFC(app,f,a,b,h);
                        dydxExata = dfdx(x);
                        erroDFC = abs(dydxExata-dydxDFC);
                        plot(app.UIAxes_2,x,y,"-r*");
                        hold(app.UIAxes_2,"on");
                        plot(app.UIAxes_2,x,dydxExata,"-g+");
                        plot(app.UIAxes_2,x,dydxDFC,"-bo");
                        %plot(app.UIAxes_2,x,erroDFC,"-k");
                        hold(app.UIAxes_2,"off");
                        title(app.UIAxes_2, "Centradas (3 pontos)");
                        legendaGrafico = ["y=f(x)","dydxExata","dydxDFC"];
                        legend(app.UIAxes_2,legendaGrafico);
                        grid(app.UIAxes_2,"on");

                    case "2ª Derivada (3 pontos)"
                        
                        dydxsym = diff(f, x);
                        dfdx =@(x) eval(vectorize(char(dydxsym)));
                        [x,y,dydxD2] = NDerivacaoD2(app,f,a,b,h);
                        dydxExata = dfdx(x);
                        erroD2 = abs(dydxExata-dydxD2);
                        plot(app.UIAxes_2,x,y,"-r*");
                        hold(app.UIAxes_2,"on");
                        plot(app.UIAxes_2,x,dydxExata,"-g+");
                        plot(app.UIAxes_2,x,dydxD2,"-bo");   
                        %plot(app.UIAxes_2,x,erroD2,"-k");
                        hold(app.UIAxes_2,"off");
                        title(app.UIAxes_2, "2ª Derivada (3 pontos)");
                        legendaGrafico = ["y=f(x)","dydxExata","dydxD2"];
                        legend(app.UIAxes_2,legendaGrafico);
                        grid(app.UIAxes_2,"on");
                        
                    case "Todas"
                        
                        syms x
                        strFx = app.eFx_2.Value;
                        f_sym = str2sym(strFx);
                        dfdx_sym = diff(f_sym, x);
                        dfdx = matlabFunction(dfdx_sym, 'Vars', {x});
                        f = @(x) eval(vectorize(strFx));
                    
                        [xDFP,yDFP,dydxDFP] = NDerivacaoDFP(app,f,a,b,h);
                        [xDFR,yDFR,dydxDFR] = NDerivacaoDFR(app,f,a,b,h);
                        [xDFP3,yDFP3,dydxDFP3] = NDerivacaoDFP3(app,f,a,b,h);
                        [xDFR3,yDFR3,dydxDFR3] = NDerivacaoDFR3(app,f,a,b,h);
                        [xDFC,yDFC,dydxDFC] = NDerivacaoDFC(app,f,a,b,h);
                        [xD2,yD2,dydxD2] = NDerivacaoD2(app,f,a,b,h);
                    
                        x = xDFP;
                        dydxExata = dfdx(x);
                    
                        erroDFP = abs(dydxExata-dydxDFP);
                        erroDFR = abs(dydxExata-dydxDFR);
                        erroDFP3 = abs(dydxExata-dydxDFP3);
                        erroDFR3 = abs(dydxExata-dydxDFR3);
                        erroDFC = abs(dydxExata-dydxDFC);
                        erroD2 = abs(dydxExata-dydxD2);
                    
                        plot(app.UIAxes_2,xDFP,yDFP,"-r*");
                        hold(app.UIAxes_2,"on");
                        plot(app.UIAxes_2,xDFP,dydxExata,"-g+");
                        
                        plot(app.UIAxes_2,xDFP,dydxDFP,"-bo");
                        plot(app.UIAxes_2,xDFR,dydxDFR,"-c");
                        plot(app.UIAxes_2,xDFP3,dydxDFP3,"-mo");
                        plot(app.UIAxes_2,xDFR3,dydxDFR3,"-yo");
                        plot(app.UIAxes_2,xDFC,dydxDFC,"-ko");
                        plot(app.UIAxes_2,xD2,dydxD2,"Color",[1 0.5 0],"LineStyle","-","Marker","o");
                        
                        hold(app.UIAxes_2,"off");
                        title(app.UIAxes_2, "Todas");
                        legendaGrafico = ["y = f(x)", "Derivada Exata", "DFP", "DFR", "DFP3", "DFR3", "DFC", "D2"];
                        legend(app.UIAxes_2,legendaGrafico);
                        grid(app.UIAxes_2,"on");
                end
            catch Me
                errordlg(Me.message,"ERRO","modal")
            end

        end

        % Button pushed function: ExecutarButton_IntNum
        function ExecutarButton_IntNumPushed(app, event)
            erroCounter = 0;
            erroString  = "";

            % Leitura dos campos
            strFx = app.eFx_intNum.Value;
            a = app.aField.Value;
            b    = app.bField.Value;
            n    = app.nEditField_SubInt.Value;

            % Valida f(t,u,v)
            try
                f =@(x) eval(vectorize(strFx));
                % avalia em ponto de teste
                f(1);
                app.eFx_intNum.BackgroundColor = [1,1,1];
            catch
                app.eFx_intNum.BackgroundColor = [1,0.8,0.8];
                erroCounter = erroCounter + 1;
                erroString = erroString + "Introduza uma função válida para f." + newline;
            end

            % Valida intervalo [a,b]
            if ~isnumeric(a) || ~isnumeric(b) || isnan(a) || isnan(b)
                app.aField.BackgroundColor = [1,0.8,0.8];
                app.bField.BackgroundColor = [1,0.8,0.8];
                erroCounter = erroCounter + 1;
                erroString = erroString + "Os campos a e b devem ser números válidos." + newline;
            elseif b <= a
                app.aField.BackgroundColor = [1,0.8,0.8];
                app.bField.BackgroundColor = [1,0.8,0.8];
                erroCounter = erroCounter + 1;
                erroString = erroString + "Deve ser b > a no intervalo." + newline;
            else
                app.aField.BackgroundColor = [1,1,1];
                app.bField.BackgroundColor = [1,1,1];
            end


            % Valida número de passos n
            if ~isnumeric(n) || n < 2 || floor(n)~=n
                app.nEditField_SubInt.BackgroundColor = [1,0.8,0.8];
                erroCounter = erroCounter + 1;
                erroString = erroString + "Introduza um N inteiro ≥ 2." + newline;
            else
                app.nEditField_SubInt.BackgroundColor = [1,1,1];
            end

            % Se houver erros, mostra diálogo e sai
            if erroCounter > 0
                errordlg(erroString, "Erro de Validação", "modal");
                return;
            end
            
            syms y(x)
            f =@(x) eval(vectorize(app.eFx_intNum.Value));
            selectedMethod = app.minButGroup.SelectedObject;
            
            flag = 1;
            try
                switch selectedMethod.Text
                    case "R Trapézios"
                        [prox, exact, errorValue] = Fregra_trapezios(app, f ,app.aField.Value , app.bField.Value, app.nEditField_SubInt.Value);
                    case "R Simpson"
                        if mod(app.nEditField_SubInt.Value, 2) == 0
                            [prox, exact, errorValue] = Fregra_simpson(app, f ,app.aField.Value , app.bField.Value, app.nEditField_SubInt.Value);
                        else
                            errordlg("Erro, insira numero de divisões par");
                            flag = 0;
                        end
                    % case "Todas"
                    %     [proxT, exactT, errorValueT] = Fregra_trapezios(app, f ,app.aField.Value , app.bField.Value, app.nEditField_SubInt.Value);
                    %     [proxS, exactS, errorValueS] = Fregra_simpson(app, f ,app.aField.Value , app.bField.Value, app.nEditField_SubInt.Value);
                end
                    
                    % if selectedMethod.Text == "Todas"
                    %     if errorValueT < errorValueS
                    %         app.AproxEditField.Value = proxT;
                    %         app.ExatoEditField.Value = exactT;
                    %         app.ErroEditField.Value = errorValueT;
                    % 
                    %     else
                    %         app.AproxEditField.Value = proxS;
                    %         app.ExatoEditField.Value = exactS;
                    %         app.ErroEditField.Value = errorValueS;
                    %         best = "Simpson";
                    %     end      
                    
                    if flag == 1
                        app.AproxEditField.Value = prox;
                        app.ExatoEditField.Value = exact;
                        app.ErroEditField.Value = errorValue;
                        
                        [x, y] = evalFunc(app, f, app.aField.Value, app.bField.Value);
                        
                        plot(app.UIAxes_3, x, y);
                        title(app.UIAxes_3, selectedMethod.Text);
                        xlabel(app.UIAxes_3, 'x');
                        ylabel(app.UIAxes_3, 'y');
                        grid(app.UIAxes_3, 'on');
                    end

            catch Me
                errordlg(Me.message,"ERRO","modal");
            end

        end

        % Button pushed function: ResetButton_IntNum
        function ResetButton_IntNumPushed(app, event)
            
            app.AproxEditField.Value = 0;
            app.ExatoEditField.Value = 0;
            app.ErroEditField.Value = 0;

            app.eFx_intNum.Value = 'sin(x)';
            app.aField.Value = 0;
            app.bField.Value = 5;
            app.nEditField_SubInt.Value = 20;
            
            app.minButGroup.SelectedObject = app.RTrapziosButton;
            ExecutarButton_IntNumPushed(app, []);
        end

        % Button pushed function: ResetButton_2
        function ResetButton_2Pushed(app, event)
            app.eFx_2.Value = "sin(x)";
            app.eA_2.Value = -6;
            app.eH1_2.Value = 0.2;
            app.eB_2.Value = 6;
            app.DerivaoNumricaButtonGroup.SelectedObject = app.DFProgressivasButton;

            ExecutarButton_2Pushed(app, []);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.eFxy.Value = "x^2+y^2";
            app.eA.Value = -2;
            app.eH1.Value = 0.2;
            app.eB.Value = 2;
            app.eC.Value = -2;
            app.eH2.Value = 0.2;
            app.eD.Value = 2;

            ExecutarButtonPushed(app, []);
        end

        % Button pushed function: ConverterButton
        function ConverterButtonPushed(app, event)
            try
                x = str2num(app.xEditField.Value);
                y = str2num(app.yEditField.Value);
                z = str2num(app.zEditField.Value);
            catch
                uialert(app.UIFigure, 'Um ou mais valores não são expressões matemáticas válidas.', 'Erro de Entrada');
                return;
            end
            
            if isempty(x) || isempty(y) || isempty(z) || ~isnumeric(x) || ~isnumeric(y) || ~isnumeric(z) || ...
               ~isscalar(x) || ~isscalar(y) || ~isscalar(z) || any(isnan([x, y, z]))
                uialert(app.UIFigure, 'Um ou mais valores não são numéricos válidos ou não são escalares.', 'Erro de Entrada');
                return;
            end
            
            choice = app.ConversoButtonGroup.SelectedObject;
            
            switch choice.Text
                case 'Cartesiana para Polar'
                    [theta, rho] = cart2pol(x, y);
                    theta_deg = rad2deg(theta);
                    
                    app.Label_8.Text = sprintf('Theta: %.2f °', theta_deg);
                    app.Label_9.Text = sprintf('Rho: %.2f', rho);
                    app.Label_10.Text = '';
            
                case 'Cartesiana para Cilindro'
                    [theta, rho, zOut] = cart2pol(x, y, z);
                    theta_deg = rad2deg(theta);
                    
                    app.Label_8.Text = sprintf('Theta: %.2f °', theta_deg);
                    app.Label_9.Text = sprintf('Rho: %.2f', rho);
                    app.Label_10.Text = sprintf('Z: %.2f', zOut);
            
                case 'Cartesiana para Esférica'
                    [az, el, r] = cart2sph(x, y, z);
                    az_deg = rad2deg(az);
                    el_deg = rad2deg(el);
                    
                    app.Label_8.Text = sprintf('Azimuth: %.2f °', az_deg);
                    app.Label_9.Text = sprintf('Elevation: %.2f °', el_deg);
                    app.Label_10.Text = sprintf('R: %.2f', r);
            end
        end

        % Button pushed function: btEnviar
        function btEnviarButtonPushed(app, event)
            app.btEnviar.Enable = 'off';
            app.eResposta.Value = "Thinking...";
            pause(1)

            ExecutarButtonPushed(app, []);
            ExecutarButton_2Pushed(app, []);
            ExecutarButton_IntNumPushed(app, []);
            pause(1)

            aiHider(app);
            pause(1)

            app.btEnviar.Enable = 'on';
        end

        % Button pushed function: ResetButton_3
        function ResetButton_3Pushed(app, event)
            app.xEditField.Value = '0';
            app.yEditField.Value = '2*pi';
            app.zEditField.Value = '50';
            app.ConversoButtonGroup.SelectedObject = app.CartesianaparaPolarButton;

            app.Label_8.Text = '';
            app.Label_9.Text = '';
            app.Label_10.Text = '';

            ConverterButtonPushed(app, []);
        end

        % Button pushed function: ConverterButton_2
        function ConverterButton_2Pushed(app, event)
            try
                x = str2num(app.ThetaEditField.Value);
                y = str2num(app.RhoEditField.Value);
                z = str2num(app.zEditField_2.Value);
                
                if isempty(x) || isempty(y) || ~isnumeric(x) || ~isnumeric(y) || ~isscalar(x) || ~isscalar(y) || isnan(x) || isnan(y)
                    uialert(app.UIFigure, 'Um ou mais valores não são numéricos válidos ou não são escalares.', 'Erro de Entrada');
                    return;
                end
                
                choice = app.ConversoButtonGroup_2.SelectedObject;
                
                switch choice.Text
                    case 'Polar para Cartesiana'
                        theta_rad = deg2rad(x);
                        rho = y;
                        
                        [xOut, yOut] = pol2cart(theta_rad, rho);
                        
                        app.Label_11.Text = sprintf('X: %.2f', xOut);
                        app.Label_12.Text = sprintf('Y: %.2f', yOut);
                        app.Label_13.Text = '';
                        
                    case 'Cilindro para Cartesiana'
                        if isempty(z) || ~isnumeric(z) || ~isscalar(z) || isnan(z)
                            uialert(app.UIFigure, 'O valor de Z não é numérico válido ou não é escalar.', 'Erro de Entrada');
                            return;
                        end
                        
                        theta_rad = deg2rad(x);
                        rho = y;
                        zOut = z;
                        
                        [xOut, yOut, zOut] = pol2cart(theta_rad, rho, zOut);
                        
                        app.Label_11.Text = sprintf('X: %.2f', xOut);
                        app.Label_12.Text = sprintf('Y: %.2f', yOut);
                        app.Label_13.Text = sprintf('Z: %.2f', zOut);
                        
                    case 'Esférica para Cartesiana'
                        if isempty(z) || ~isnumeric(z) || ~isscalar(z) || isnan(z)
                            uialert(app.UIFigure, 'O valor de R não é numérico válido ou não é escalar.', 'Erro de Entrada');
                            return;
                        end
                        
                        az_rad = deg2rad(x);
                        el_rad = deg2rad(y);
                        r = z;
                        
                        [xOut, yOut, zOut] = sph2cart(az_rad, el_rad, r);
                        
                        app.Label_11.Text = sprintf('X: %.2f', xOut);
                        app.Label_12.Text = sprintf('Y: %.2f', yOut);
                        app.Label_13.Text = sprintf('Z: %.2f', zOut);
                end
            catch
                uialert(app.UIFigure, 'Um ou mais valores não são expressões matemáticas válidas.', 'Erro de Entrada');
                return;
            end
        end

        % Selection changed function: ConversoButtonGroup_2
        function ConversoButtonGroup_2SelectionChanged(app, event)
            selectedButton = app.ConversoButtonGroup_2.SelectedObject;
    
            switch selectedButton.Text
                case 'Polar para Cartesiana'
                    app.ThetaEditFieldLabel.Text = 'Theta (°)';
                    app.RhoEditFieldLabel.Text = 'Rho';
                    app.zEditField_2Label.Text = '';
                    app.zEditField_2.Visible = "off";
                case 'Cilindro para Cartesiana'
                    app.ThetaEditFieldLabel.Text = 'Theta (°)';
                    app.RhoEditFieldLabel.Text = 'Rho';
                    app.zEditField_2.Visible = "on";
                    app.zEditField_2Label.Visible = "on";
                    app.zEditField_2Label.Text = 'Z';
                case 'Esférica para Cartesiana'
                    app.ThetaEditFieldLabel.Text = 'Azimuth (°)';
                    app.RhoEditFieldLabel.Text = 'Elevation (°)';
                    app.zEditField_2.Visible = "on";
                    app.zEditField_2Label.Visible = "on";
                    app.zEditField_2Label.Text = 'R';
            end
        end

        % Button pushed function: ResetButton_4
        function ResetButton_4Pushed(app, event)
            app.ThetaEditField.Value = '90';
            app.RhoEditField.Value = '2*pi';
            app.zEditField_2.Value = '0';
            app.ConversoButtonGroup_2.SelectedObject = app.PolarparaCartesianaButton;

            app.Label_11.Text = '';
            app.Label_12.Text = '';
            app.Label_13.Text = '';

            ConverterButton_2Pushed(app, []);
        end

        % Selection changed function: ConversoButtonGroup
        function ConversoButtonGroupSelectionChanged(app, event)
            selectedButton = app.ConversoButtonGroup.SelectedObject;
            
            switch selectedButton.Text
                case 'Cartesiana para Polar'
                    app.zEditField.Visible = "off";
                    app.zEditFieldLabel.Visible = "off";
                case 'Cartesiana para Cilindro'
                    app.zEditField.Visible = "on";
                    app.zEditFieldLabel.Visible = "on";
                case 'Cartesiana para Esférica'
                    app.zEditField.Visible = "on";
                    app.zEditFieldLabel.Visible = "on";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.zfxyTab = uitab(app.TabGroup);
            app.zfxyTab.Title = 'z = f(x,y)';
            app.UIAxes = uiaxes(app.zfxyTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Panel = uipanel(app.zfxyTab);
            app.fxyEditFieldLabel = uilabel(app.Panel);
            app.fxyEditFieldLabel.Text = 'f(x,y) =';
            app.eFxy = uieditfield(app.Panel, 'text');
            app.eFxy.Value = 'x^2+y^2';
            app.aEditFieldLabel = uilabel(app.Panel);
            app.aEditFieldLabel.Text = 'a';
            app.eA = uieditfield(app.Panel, 'numeric');
            app.eA.Value = -2;
            app.aEditFieldLabel_2 = uilabel(app.Panel);
            app.aEditFieldLabel_2.Text = 'h1';
            app.eH1 = uieditfield(app.Panel, 'numeric');
            app.eH1.Value = 0.2;
            app.aEditFieldLabel_3 = uilabel(app.Panel);
            app.aEditFieldLabel_3.Text = 'b';
            app.eB = uieditfield(app.Panel, 'numeric');
            app.eB.Value = 2;
            app.xLabel = uilabel(app.Panel);
            app.xLabel.Text = 'x =';
            app.Label = uilabel(app.Panel);
            app.Label.Text = ';';
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = ';';
            app.eD = uieditfield(app.Panel, 'numeric');
            app.eD.Value = 2;
            app.Label_3 = uilabel(app.Panel);
            app.Label_3.Text = ';';
            app.aEditFieldLabel_4 = uilabel(app.Panel);
            app.aEditFieldLabel_4.Text = 'd';
            app.eH2 = uieditfield(app.Panel, 'numeric');
            app.eH2.Value = 0.2;
            app.aEditFieldLabel_5 = uilabel(app.Panel);
            app.aEditFieldLabel_5.Text = 'h2';
            app.Label_4 = uilabel(app.Panel);
            app.Label_4.Text = ';';
            app.cLabel = uilabel(app.Panel);
            app.cLabel.Text = 'c';
            app.eC = uieditfield(app.Panel, 'numeric');
            app.eC.Value = -2;
            app.yLabel = uilabel(app.Panel);
            app.yLabel.Text = 'y =';
            app.ExecutarButton = uibutton(app.Panel, 'push');
            app.ExecutarButton.ButtonPushedFcn = createCallbackFcn(app, @ExecutarButtonPushed, true);
            app.ExecutarButton.Text = 'Executar';
            app.ResetButton = uibutton(app.Panel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.LabelDParcial = uilabel(app.Panel);
            app.LabelDParcial.Interpreter = 'latex';
            app.LabelDParcial.Text = 'DParcial';
            app.Panel_3 = uipanel(app.Panel);
            app.LabelEqLaplace = uilabel(app.Panel_3);
            app.LabelEqLaplace.Interpreter = 'latex';
            app.LabelEqLaplace.Text = 'EqLaplace';
            app.LabelHarmonica = uilabel(app.Panel_3);
            app.LabelHarmonica.Text = 'A função não é harmónica';
            app.yfxTab = uitab(app.TabGroup);
            app.yfxTab.Title = 'y = f(x)';
            app.UIAxes_2 = uiaxes(app.yfxTab);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.Panel_2 = uipanel(app.yfxTab);
            app.fxLabel = uilabel(app.Panel_2);
            app.fxLabel.Text = 'f(x) = ';
            app.eFx_2 = uieditfield(app.Panel_2, 'text');
            app.eFx_2.Value = 'sin(x)';
            app.aEditFieldLabel_6 = uilabel(app.Panel_2);
            app.aEditFieldLabel_6.Text = 'a';
            app.eA_2 = uieditfield(app.Panel_2, 'numeric');
            app.eA_2.Value = -6;
            app.aEditFieldLabel_7 = uilabel(app.Panel_2);
            app.aEditFieldLabel_7.Text = 'h1';
            app.eH1_2 = uieditfield(app.Panel_2, 'numeric');
            app.eH1_2.Value = 0.2;
            app.aEditFieldLabel_8 = uilabel(app.Panel_2);
            app.aEditFieldLabel_8.Text = 'b';
            app.eB_2 = uieditfield(app.Panel_2, 'numeric');
            app.eB_2.Value = 6;
            app.xLabel_2 = uilabel(app.Panel_2);
            app.xLabel_2.Text = 'x =';
            app.Label_5 = uilabel(app.Panel_2);
            app.Label_5.Text = ';';
            app.Label_6 = uilabel(app.Panel_2);
            app.Label_6.Text = ';';
            app.ExecutarButton_2 = uibutton(app.Panel_2, 'push');
            app.ExecutarButton_2.ButtonPushedFcn = createCallbackFcn(app, @ExecutarButton_2Pushed, true);
            app.ExecutarButton_2.Text = 'Executar';
            app.ResetButton_2 = uibutton(app.Panel_2, 'push');
            app.ResetButton_2.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_2Pushed, true);
            app.ResetButton_2.Text = 'Reset';
            app.DerivaoNumricaButtonGroup = uibuttongroup(app.Panel_2);
            app.DerivaoNumricaButtonGroup.Title = 'Derivação Numérica';
            app.DFProgressivasButton = uiradiobutton(app.DerivaoNumricaButtonGroup);
            app.DFProgressivasButton.Text = 'DFProgressivas';
            app.DFProgressivasButton.Value = true;
            app.Regressivas2pontosButton = uiradiobutton(app.DerivaoNumricaButtonGroup);
            app.Regressivas2pontosButton.Text = 'Regressivas (2 pontos)';
            app.Progressivas3pontosButton = uiradiobutton(app.DerivaoNumricaButtonGroup);
            app.Progressivas3pontosButton.Text = 'Progressivas (3 pontos)';
            app.Regressivas3pontosButton = uiradiobutton(app.DerivaoNumricaButtonGroup);
            app.Regressivas3pontosButton.Text = 'Regressivas (3 pontos)';
            app.Centradas3pontosButton = uiradiobutton(app.DerivaoNumricaButtonGroup);
            app.Centradas3pontosButton.Text = 'Centradas (3 pontos)';
            app.Derivada3pontosButton = uiradiobutton(app.DerivaoNumricaButtonGroup);
            app.Derivada3pontosButton.Text = '2ª Derivada (3 pontos)';
            app.TodasButton = uiradiobutton(app.DerivaoNumricaButtonGroup);
            app.TodasButton.Text = 'Todas';
            app.IntegNumTab = uitab(app.TabGroup);
            app.IntegNumTab.Title = 'IntegNum';
            app.UIAxes_3 = uiaxes(app.IntegNumTab);
            title(app.UIAxes_3, 'Title')
            app.Panel_4 = uipanel(app.IntegNumTab);
            app.fxLabel_2 = uilabel(app.Panel_4);
            app.fxLabel_2.Text = 'f(x) = ';
            app.eFx_intNum = uieditfield(app.Panel_4, 'text');
            app.eFx_intNum.Value = 'sin(x)';
            app.aEditFieldLabel_9 = uilabel(app.Panel_4);
            app.aEditFieldLabel_9.Text = 'a';
            app.aField = uieditfield(app.Panel_4, 'numeric');
            app.bLabel = uilabel(app.Panel_4);
            app.bLabel.Text = {'b'; ''};
            app.bField = uieditfield(app.Panel_4, 'numeric');
            app.bField.Value = 5;
            app.Label_7 = uilabel(app.Panel_4);
            app.Label_7.Text = ';';
            app.ExecutarButton_IntNum = uibutton(app.Panel_4, 'push');
            app.ExecutarButton_IntNum.ButtonPushedFcn = createCallbackFcn(app, @ExecutarButton_IntNumPushed, true);
            app.ExecutarButton_IntNum.Text = 'Executar';
            app.ResetButton_IntNum = uibutton(app.Panel_4, 'push');
            app.ResetButton_IntNum.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_IntNumPushed, true);
            app.ResetButton_IntNum.Text = 'Reset';
            app.NmerodeSubintervalosPanel = uibuttongroup(app.Panel_4);
            app.NmerodeSubintervalosPanel.Title = 'Número de Subintervalos';
            app.nEditFieldLabel = uilabel(app.NmerodeSubintervalosPanel);
            app.nEditFieldLabel.Text = 'n = ';
            app.nEditField_SubInt = uieditfield(app.NmerodeSubintervalosPanel, 'numeric');
            app.nEditField_SubInt.Value = 20;
            app.minButGroup = uibuttongroup(app.Panel_4);
            app.minButGroup.Title = 'Métodos de Integração Numérica';
            app.RTrapziosButton = uiradiobutton(app.minButGroup);
            app.RTrapziosButton.Text = 'R Trapézios';
            app.RTrapziosButton.Value = true;
            app.RSimpsonButton = uiradiobutton(app.minButGroup);
            app.RSimpsonButton.Text = 'R Simpson';
            app.ResultadoPanel_IntNum = uipanel(app.IntegNumTab);
            app.ResultadoPanel_IntNum.Title = 'Resultado';
            app.ExatoEditFieldLabel = uilabel(app.ResultadoPanel_IntNum);
            app.ExatoEditFieldLabel.Text = 'Exato';
            app.ExatoEditField = uieditfield(app.ResultadoPanel_IntNum, 'numeric');
            app.ExatoEditField.Editable = 'off';
            app.AproxEditFieldLabel = uilabel(app.ResultadoPanel_IntNum);
            app.AproxEditFieldLabel.Text = 'Aprox';
            app.AproxEditField = uieditfield(app.ResultadoPanel_IntNum, 'numeric');
            app.AproxEditField.Editable = 'off';
            app.ErroEditFieldLabel = uilabel(app.ResultadoPanel_IntNum);
            app.ErroEditFieldLabel.Text = 'Erro';
            app.ErroEditField = uieditfield(app.ResultadoPanel_IntNum, 'numeric');
            app.ErroEditField.Editable = 'off';
            app.CoordenadasTab = uitab(app.TabGroup);
            app.CoordenadasTab.Title = 'Coordenadas';
            app.Panel_5 = uipanel(app.CoordenadasTab);
            app.ConverterButton = uibutton(app.Panel_5, 'push');
            app.ConverterButton.ButtonPushedFcn = createCallbackFcn(app, @ConverterButtonPushed, true);
            app.ConverterButton.Text = 'Converter';
            app.ResetButton_3 = uibutton(app.Panel_5, 'push');
            app.ResetButton_3.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_3Pushed, true);
            app.ResetButton_3.Text = 'Reset';
            app.ConversoButtonGroup = uibuttongroup(app.Panel_5);
            app.ConversoButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ConversoButtonGroupSelectionChanged, true);
            app.ConversoButtonGroup.Title = 'Conversão';
            app.CartesianaparaPolarButton = uiradiobutton(app.ConversoButtonGroup);
            app.CartesianaparaPolarButton.Text = 'Cartesiana para Polar';
            app.CartesianaparaPolarButton.Value = true;
            app.CartesianaparaCilindroButton = uiradiobutton(app.ConversoButtonGroup);
            app.CartesianaparaCilindroButton.Text = 'Cartesiana para Cilindro';
            app.CartesianaparaEsfricaButton = uiradiobutton(app.ConversoButtonGroup);
            app.CartesianaparaEsfricaButton.Text = 'Cartesiana para Esférica';
            app.Label_8 = uilabel(app.Panel_5);
            app.Label_8.Text = '.';
            app.Label_9 = uilabel(app.Panel_5);
            app.Label_9.Text = '.';
            app.Label_10 = uilabel(app.Panel_5);
            app.Label_10.Text = '.';
            app.yEditFieldLabel = uilabel(app.Panel_5);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.Panel_5, 'text');
            app.yEditField.Value = '2*pi';
            app.zEditFieldLabel = uilabel(app.Panel_5);
            app.zEditFieldLabel.Visible = 'off';
            app.zEditFieldLabel.Text = 'z';
            app.zEditField = uieditfield(app.Panel_5, 'text');
            app.zEditField.Visible = 'off';
            app.zEditField.Value = '50';
            app.xEditFieldLabel = uilabel(app.Panel_5);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.Panel_5, 'text');
            app.xEditField.Value = '0';
            app.Panel_6 = uipanel(app.CoordenadasTab);
            app.ConverterButton_2 = uibutton(app.Panel_6, 'push');
            app.ConverterButton_2.ButtonPushedFcn = createCallbackFcn(app, @ConverterButton_2Pushed, true);
            app.ConverterButton_2.Text = 'Converter';
            app.ResetButton_4 = uibutton(app.Panel_6, 'push');
            app.ResetButton_4.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_4Pushed, true);
            app.ResetButton_4.Text = 'Reset';
            app.ConversoButtonGroup_2 = uibuttongroup(app.Panel_6);
            app.ConversoButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @ConversoButtonGroup_2SelectionChanged, true);
            app.ConversoButtonGroup_2.Title = 'Conversão';
            app.PolarparaCartesianaButton = uiradiobutton(app.ConversoButtonGroup_2);
            app.PolarparaCartesianaButton.Text = 'Polar para Cartesiana';
            app.PolarparaCartesianaButton.Value = true;
            app.CilindroparaCartesianaButton = uiradiobutton(app.ConversoButtonGroup_2);
            app.CilindroparaCartesianaButton.Text = 'Cilindro para Cartesiana';
            app.EsfricaparaCartesianaButton = uiradiobutton(app.ConversoButtonGroup_2);
            app.EsfricaparaCartesianaButton.Text = 'Esférica para Cartesiana';
            app.Label_11 = uilabel(app.Panel_6);
            app.Label_11.Text = '.';
            app.Label_12 = uilabel(app.Panel_6);
            app.Label_12.Text = '.';
            app.Label_13 = uilabel(app.Panel_6);
            app.Label_13.Text = '.';
            app.RhoEditFieldLabel = uilabel(app.Panel_6);
            app.RhoEditFieldLabel.Text = 'Rho';
            app.RhoEditField = uieditfield(app.Panel_6, 'text');
            app.RhoEditField.Value = '2*pi';
            app.zEditField_2Label = uilabel(app.Panel_6);
            app.zEditField_2Label.Visible = 'off';
            app.zEditField_2Label.Text = 'z';
            app.zEditField_2 = uieditfield(app.Panel_6, 'text');
            app.zEditField_2.Visible = 'off';
            app.zEditField_2.Value = '50';
            app.ThetaEditFieldLabel = uilabel(app.Panel_6);
            app.ThetaEditFieldLabel.Text = 'Theta (°)';
            app.ThetaEditField = uieditfield(app.Panel_6, 'text');
            app.ThetaEditField.Value = '90';
            app.AIHelperTab = uitab(app.TabGroup);
            app.AIHelperTab.Title = 'AI Helper';
            app.btEnviar = uibutton(app.AIHelperTab, 'push');
            app.btEnviar.ButtonPushedFcn = createCallbackFcn(app, @btEnviarButtonPushed, true);
            app.btEnviar.Text = 'Enviar';
            app.PerguntaTextAreaLabel = uilabel(app.AIHelperTab);
            app.PerguntaTextAreaLabel.Text = 'Pergunta';
            app.ePergunta = uitextarea(app.AIHelperTab);
            app.ePergunta.Placeholder = 'Explica esta TAB';
            app.RespostaTextAreaLabel = uilabel(app.AIHelperTab);
            app.RespostaTextAreaLabel.Text = 'Resposta';
            app.eResposta = uitextarea(app.AIHelperTab);
            app.eResposta.Editable = 'off';
            app.TabSelecionadaDropDownLabel = uilabel(app.AIHelperTab);
            app.TabSelecionadaDropDownLabel.Text = 'Tab Selecionada';
            app.TabSelecionadaDropDown = uidropdown(app.AIHelperTab);
            app.TabSelecionadaDropDown.Items = {'z = f(x,y)', 'y = f(x)', 'IntegNum', 'Coordenadas'};
            app.TabSelecionadaDropDown.Value = 'z = f(x,y)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appMaquina03CDI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end