classdef xE < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        HelpMenu                    matlab.ui.container.Menu
        HelpMenu_2                  matlab.ui.container.Menu
        ClassifySingleImageButton   matlab.ui.control.Button
        RunButton                   matlab.ui.control.Button
        ClearNetButton              matlab.ui.control.Button
        SaveNetButton               matlab.ui.control.Button
        LoadNetButton               matlab.ui.control.Button
        LoadedNetTextArea           matlab.ui.control.TextArea
        LoadedNetTextAreaLabel      matlab.ui.control.Label
        ExportTableButton           matlab.ui.control.Button
        ClearInfoButton             matlab.ui.control.Button
        LoadedDatasetTextArea       matlab.ui.control.TextArea
        LoadedDatasetTextAreaLabel  matlab.ui.control.Label
        LoadDatasetButton           matlab.ui.control.Button
        UITable                     matlab.ui.control.Table
        TrainButton                 matlab.ui.control.Button
        TestRatioEditField          matlab.ui.control.NumericEditField
        TestRatioEditFieldLabel     matlab.ui.control.Label
        ValRatioEditField           matlab.ui.control.NumericEditField
        ValRatioEditFieldLabel      matlab.ui.control.Label
        TrainRatioEditField         matlab.ui.control.NumericEditField
        TrainRatioEditFieldLabel    matlab.ui.control.Label
        InfoTextArea                matlab.ui.control.TextArea
        TextAreaLabel               matlab.ui.control.Label
        TopologyEditField           matlab.ui.control.EditField
        TopologyEditFieldLabel      matlab.ui.control.Label
        PerformFcnDropDown          matlab.ui.control.DropDown
        PerformFcnDropDownLabel     matlab.ui.control.Label
        TrainFcnDropDown            matlab.ui.control.DropDown
        TrainFcnDropDownLabel       matlab.ui.control.Label
        EndTrainFcnDropDown         matlab.ui.control.DropDown
        EndTrainFcnDropDownLabel    matlab.ui.control.Label
        ActivationFcnDropDown       matlab.ui.control.DropDown
        ActivationFcnDropDownLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        Images
        ImageNames
        Labels
        DatasetFolder
        Network
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: HelpMenu_2
        function HelpMenu_2Selected(app, event)
            open("filesui\help.html");
        end

        % Callback function
        function HelpptMenuSelected(app, event)

        end

        % Button pushed function: TrainButton
        function TrainButtonPushed(app, event)
            %disp(app.TopologyEditField.Value);
            %disp(app.ActivationFcnDropDown.Value);
            %disp(app.EndTrainFcnDropDown.Value);
            %disp(app.TrainFcnDropDown.Value);
            %disp(app.PerformFcnDropDown.Value);
            %disp(app.TrainRatioEditField.Value);
            %disp(app.ValRatioEditField.Value);
            %disp(app.TestRatioEditField.Value);

            app.InfoTextArea.Value = 'Iniciando validação dos parâmetros de treino...';
            
            topology = app.TopologyEditField.Value;
            try
                if ischar(topology) || isstring(topology)
                    topology = str2num(strrep(topology, ',', ' '));
                end
                if isempty(topology) || ~isvector(topology) || any(topology <= 0) || any(mod(topology, 1) ~= 0)
                    app.InfoTextArea.Value = [app.InfoTextArea.Value; 'Erro: Topologia deve conter apenas inteiros positivos (e.g., [50 30]).'];
                    scroll(app.InfoTextArea, 'bottom');
                    return;
                end
                if length(topology) > 10 || any(topology > 1000)
                    app.InfoTextArea.Value = [app.InfoTextArea.Value; 'Erro: Topologia muito grande (máx. 10 camadas, 1000 neurónios por camada).'];
                    scroll(app.InfoTextArea, 'bottom');
                    return;
                end
            catch
                app.InfoTextArea.Value = [app.InfoTextArea.Value; 'Erro: Formato de topologia inválido. Use inteiros positivos (e.g., [50 30]).'];
                scroll(app.InfoTextArea, 'bottom');
                return;
            end
            
            try
                trainRatio = app.TrainRatioEditField.Value;
                valRatio = app.ValRatioEditField.Value;
                testRatio = app.TestRatioEditField.Value;
                
                if ~isnumeric(trainRatio) || ~isnumeric(valRatio) || ~isnumeric(testRatio)
                    app.InfoTextArea.Value = [app.InfoTextArea.Value; 'Erro: Rácios devem ser valores numéricos.'];
                    scroll(app.InfoTextArea, 'bottom');
                    return;
                end
                
                if trainRatio <= 0 || valRatio < 0 || testRatio <= 0
                    app.InfoTextArea.Value = [app.InfoTextArea.Value; 'Erro: Rácios devem ser positivos (teste > 0, validação ≥ 0).'];
                    scroll(app.InfoTextArea, 'bottom');
                    return;
                end
                
                if abs(trainRatio + valRatio + testRatio - 1) > 0.01
                    app.InfoTextArea.Value = [app.InfoTextArea.Value; 'Erro: Rácios devem somar 1 (e.g., 0,7 + 0,15 + 0,15 = 1).'];
                    scroll(app.InfoTextArea, 'bottom');
                    return;
                end
                
                if trainRatio < 0.1 || trainRatio > 0.9
                    app.InfoTextArea.Value = [app.InfoTextArea.Value; 'Erro: Rácio de treino deve estar entre 0,1 e 0,9.'];
                    scroll(app.InfoTextArea, 'bottom');
                    return;
                end
            catch
                app.InfoTextArea.Value = [app.InfoTextArea.Value; 'Erro: Rácios inválidos. Insira valores numéricos (e.g., 0,7).'];
                scroll(app.InfoTextArea, 'bottom');
                return;
            end

            folders = {'start', 'train', 'test'};
            shapes = {'circle', 'kite', 'parallelogram', 'square', 'trapezoid', 'triangle'};
            imageSize = [28, 28];
            allImages = [];
            allLabels = [];
            
            for f = 1:length(folders)
                folderName = folders{f};
                if ~exist(folderName, 'dir')
                    app.InfoTextArea.Value = [app.InfoTextArea.Value; sprintf('Erro: Pasta %s não encontrada.', folderName)];
                    scroll(app.InfoTextArea, 'bottom');
                    return;
                end
                
                for s = 1:length(shapes)
                    targetShape = shapes{s};
                    pattern = fullfile(folderName, targetShape, sprintf('%s-%s-*.png', targetShape, folderName));
                    folderImages = dir(pattern);
                    
                    if isempty(folderImages)
                        app.InfoTextArea.Value = [app.InfoTextArea.Value; sprintf('Aviso: Nenhuma imagem encontrada para %s em %s.', targetShape, folderName)];
                        continue;
                    end
                    
                    for j = 1:length(folderImages)
                        try
                            fullPath = fullfile(folderImages(j).folder, folderImages(j).name);
                            img = imread(fullPath);

                            if size(img, 3) == 3
                                img = rgb2gray(img);
                            end

                            img = imresize(img, imageSize);
                            img = imbinarize(img, 0.5);
                            allImages = [allImages, img(:)];
                            
                            label = zeros(6, 1);
                            label(s) = 1;
                            allLabels = [allLabels, label];
                        catch err
                            app.InfoTextArea.Value = [app.InfoTextArea.Value; sprintf('Erro ao processar %s: %s', fullPath, err.message)];
                            scroll(app.InfoTextArea, 'bottom');
                            return;
                        end
                    end
                end
            end
            
            if isempty(allImages) || isempty(allLabels)
                app.InfoTextArea.Value = [app.InfoTextArea.Value; 'Erro: Nenhuma imagem válida carregada das pastas start, train, test.'];
                scroll(app.InfoTextArea, 'bottom');
                return;
            end
            
            if size(allImages, 1) ~= 784 || size(allLabels, 1) ~= 6 || size(allImages, 2) ~= size(allLabels, 2)
                app.InfoTextArea.Value = [app.InfoTextArea.Value; 'Erro: Dados inválidos. Imagens devem ser 784xN, etiquetas 6xN.'];
                scroll(app.InfoTextArea, 'bottom');
                return;
            end

            app.InfoTextArea.Value = [app.InfoTextArea.Value; sprintf('Carregadas %d imagens das pastas start, train, test.', size(allImages, 2))];
            
            app.InfoTextArea.Value = [app.InfoTextArea.Value; 'Todos os parâmetros válidos. Iniciando treino da rede...'];
            scroll(app.InfoTextArea, 'bottom');

            actFcn = app.ActivationFcnDropDown.Value;
            endFcn = app.EndTrainFcnDropDown.Value;
            perfFcn = app.PerformFcnDropDown.Value;
            trFcn = app.TrainFcnDropDown.Value;

            %disp(['Testando: Topologia ', mat2str(topology), ', Ativação ', actFcn, ', Saída ', endFcn, ', Perda ', perfFcn, ', Treino ', trFcn, ', Divisão ', num2str(trainRatio*100), '/', num2str(valRatio*100), '/', num2str(testRatio*100)]);

            net = feedforwardnet(topology);
            net.trainFcn = trFcn;
            numLayers = length(topology);
            for l = 1:numLayers
                net.layers{l}.transferFcn = actFcn;
            end
            net.layers{end}.transferFcn = endFcn;
            net.divideFcn = 'dividerand';
            net.divideParam.trainRatio = trainRatio;
            net.divideParam.valRatio = valRatio;
            net.divideParam.testRatio = testRatio;

            net.trainParam.showWindow = true;
            net.trainParam.goal = 1e-7;
            net.trainParam.epochs = 5000;

            [net, tr] = train(net, allImages, allLabels);
            outputs = net(allImages);
            [~, predicted] = max(outputs);
            [~, expected] = max(allLabels);
            globalAccuracy = sum(predicted == expected) / length(expected) * 100;

            testIndices = tr.testInd;
            if ~isempty(testIndices)
                testOutputs = net(allImages(:, testIndices));
                [~, testPredicted] = max(testOutputs);
                [~, testExpected] = max(allLabels(:, testIndices));
                testAccuracy = sum(testPredicted == testExpected) / length(testExpected) * 100;
            else
                testAccuracy = 0;
            end

            app.Network = net;

            %disp(['Treino concluído: Topologia ', mat2str(topology), ', Ativação ', app.ActivationFcnDropDown.Value, ', Saída ', app.EndTrainFcnDropDown.Value, ', Perda ', app.PerformFcnDropDown.Value, ', Treino ', app.TrainFcnDropDown.Value, ', Divisão ', num2str(trainRatio*100), '/', num2str(valRatio*100), '/', num2str(testRatio*100)]);
            %disp(['Precisão global: ', num2str(globalAccuracy), '%']);
            %disp(['Precisão de teste: ', num2str(testAccuracy), '%']);

            app.InfoTextArea.Value = [app.InfoTextArea.Value; sprintf('Treino concluído com sucesso. Precisão global: %.2f%%, Precisão de teste: %.2f%%', globalAccuracy, testAccuracy)];
            app.InfoTextArea.Value = [app.InfoTextArea.Value; sprintf('Configuração: Topologia %s, Ativação %s, Saída %s, Perda %s, Treino %s, Divisão %.0f/%.0f/%.0f', mat2str(topology), actFcn, endFcn, perfFcn, trFcn, trainRatio*100, valRatio*100, testRatio*100)];
            scroll(app.InfoTextArea, 'bottom');

            app.LoadedNetTextArea.Value = sprintf('Configuração: Topologia %s, Ativação %s, Saída %s, Perda %s, Treino %s, Divisão %.0f/%.0f/%.0f, Global %.2f%%, Test %.2f%%', mat2str(topology), actFcn, endFcn, perfFcn, trFcn, trainRatio*100, valRatio*100, testRatio*100, globalAccuracy, testAccuracy);
            app.SaveNetButton.Enable = 'on';
        end

        % Button pushed function: LoadDatasetButton
        function LoadDatasetButtonPushed(app, event)
            folderPath = uigetdir('', 'Selecione a pasta com imagens PNG');
            
            if folderPath == 0
                app.InfoTextArea.Value = [app.InfoTextArea.Value; 'Erro: Nenhuma pasta selecionada.'];
                return;
            end
            
            classes = {'circle', 'kite', 'parallelogram', 'square', 'trapezoid', 'triangle'};
            
            allPngFiles = [];
            for c = 1:length(classes)
                subfolder = fullfile(folderPath, classes{c});
                pngFiles = dir(fullfile(subfolder, '*.png'));
                if ~isempty(pngFiles)
                    for i = 1:length(pngFiles)
                        pngFiles(i).classIdx = c;
                        pngFiles(i).fullPath = fullfile(subfolder, pngFiles(i).name);
                    end
                    allPngFiles = [allPngFiles; pngFiles];
                end
            end
            
            if isempty(allPngFiles)
                app.InfoTextArea.Value = [app.InfoTextArea.Value; sprintf('Erro: Nenhuma imagem PNG encontrada nas subpastas de %s.', folderPath)];
                return;
            end
            
            numImages = length(allPngFiles);
            app.Images = zeros(784, numImages);
            app.ImageNames = cell(numImages, 1);
            app.Labels = zeros(6, numImages);
            
            classCounts = zeros(1, length(classes));
            for i = 1:numImages
                try
                    img = imread(allPngFiles(i).fullPath);
                    
                    if size(img, 3) == 3
                        img = rgb2gray(img);
                    end
                    
                    if size(img, 1) ~= 28 || size(img, 2) ~= 28
                        img = imresize(img, [28 28]);
                    end
                    
                    img = imbinarize(img);
                    app.Images(:, i) = img(:);
                    app.ImageNames{i} = fullfile(classes{allPngFiles(i).classIdx}, allPngFiles(i).name);
                    app.Labels(allPngFiles(i).classIdx, i) = 1;

                    classCounts(allPngFiles(i).classIdx) = classCounts(allPngFiles(i).classIdx) + 1;
                catch err
                    app.InfoTextArea.Value = [app.InfoTextArea.Value; sprintf('Erro ao processar %s: %s', allPngFiles(i).fullPath, err.message)];
                    return;
                end
            end
            
            app.InfoTextArea.Value = [app.InfoTextArea.Value; sprintf('Carregadas %d imagens PNG de %s com sucesso.', numImages, folderPath)];
            
            for c = 1:length(classes)
                if classCounts(c) > 0
                    app.InfoTextArea.Value = [app.InfoTextArea.Value; sprintf(' - %d imagens de %s', classCounts(c), classes{c})];
                end
            end
            
            missingClasses = classes(classCounts == 0);
            if ~isempty(missingClasses)
                app.InfoTextArea.Value = [app.InfoTextArea.Value; sprintf('Aviso: Nenhuma imagem encontrada para: %s', strjoin(missingClasses, ', '))];
            end
            
            app.DatasetFolder = folderPath;
            app.LoadedDatasetTextArea.Value = folderPath;
            
            scroll(app.InfoTextArea, 'bottom');
        end

        % Button pushed function: ClearInfoButton
        function ClearInfoButtonPushed(app, event)
            app.InfoTextArea.Value = "";
        end

        % Button pushed function: SaveNetButton
        function SaveNetButtonPushed(app, event)
            if isempty(app.Network)
                app.InfoTextArea.Value = [app.InfoTextArea.Value; 'Erro: Nenhuma rede treinada para guardar. Treine uma rede primeiro.'];
                scroll(app.InfoTextArea, 'bottom');
                return;
            end
            
            try
                [file, path] = uiputfile('*.mat', 'Guardar Rede', 'eSavedNet.mat');
                
                if file == 0
                    app.InfoTextArea.Value = [app.InfoTextArea.Value; 'Erro: Operação de guardar cancelada pelo utilizador.'];
                    scroll(app.InfoTextArea, 'bottom');
                    return;
                end
                
                net = app.Network;
                save(fullfile(path, file), 'net');
                
                app.SaveNetButton.Enable = 'off';
                
                app.InfoTextArea.Value = [app.InfoTextArea.Value; sprintf('Rede guardada com sucesso em %s.', fullfile(path, file))];
                scroll(app.InfoTextArea, 'bottom');
                
            catch err
                app.InfoTextArea.Value = [app.InfoTextArea.Value; sprintf('Erro ao guardar a rede: %s', err.message)];
                scroll(app.InfoTextArea, 'bottom');
                return;
            end
        end

        % Button pushed function: ClearNetButton
        function ClearNetButtonPushed(app, event)
            try
                app.Network = [];
                
                app.InfoTextArea.Value = [app.InfoTextArea.Value; 'Rede limpa com sucesso.'];
                scroll(app.InfoTextArea, 'bottom');
                
                app.LoadedNetTextArea.Value = '';
                app.SaveNetButton.Enable = 'off';
            catch err
                app.InfoTextArea.Value = [app.InfoTextArea.Value; sprintf('Erro ao limpar a rede: %s', err.message)];
                scroll(app.InfoTextArea, 'bottom');
                return;
            end
        end

        % Button pushed function: LoadNetButton
        function LoadNetButtonPushed(app, event)
            try
                [file, path] = uigetfile('*.mat', 'Carregar Rede');
                
                if file == 0
                    app.InfoTextArea.Value = [app.InfoTextArea.Value; 'Erro: Operação de carregar cancelada pelo utilizador.'];
                    scroll(app.InfoTextArea, 'bottom');
                    return;
                end
                
                data = load(fullfile(path, file), 'net');
                if ~isfield(data, 'net')
                    app.InfoTextArea.Value = [app.InfoTextArea.Value; 'Erro: Ficheiro selecionado não contém uma rede válida (variável "net" não encontrada).'];
                    scroll(app.InfoTextArea, 'bottom');
                    return;
                end
                
                net = data.net;
                if ~isa(net, 'network') || ~strcmp(net.name, 'Feed-Forward Neural Network')
                    app.InfoTextArea.Value = [app.InfoTextArea.Value; 'Erro: O objeto carregado não é uma rede neural feedforward válida.'];
                    scroll(app.InfoTextArea, 'bottom');
                    return;
                end
                
                app.Network = net;
                
                app.InfoTextArea.Value = [app.InfoTextArea.Value; sprintf('Rede carregada com sucesso de %s.', fullfile(path, file))];
                app.LoadedNetTextArea.Value = sprintf('Rede carregada com sucesso de %s.', fullfile(path, file));
                scroll(app.InfoTextArea, 'bottom');
                
                app.SaveNetButton.Enable = 'on';
                
           
            catch err
                app.InfoTextArea.Value = [app.InfoTextArea.Value; sprintf('Erro ao carregar a rede: %s', err.message)];
                scroll(app.InfoTextArea, 'bottom');
                return;
            end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            try
                if isempty(app.Network)
                    app.InfoTextArea.Value = [app.InfoTextArea.Value; 'Erro: Nenhuma rede carregada. Treine uma rede primeiro ou carrege de um ficheiro.'];
                    scroll(app.InfoTextArea, 'bottom');
                    return;
                end
        
                if isempty(app.Images) || isempty(app.Labels)
                    app.InfoTextArea.Value = [app.InfoTextArea.Value; 'Erro: Dados de imagens ou rótulos não carregados. Carregue o conjunto de dados primeiro.'];
                    scroll(app.InfoTextArea, 'bottom');
                    return;
                end
        
                if size(app.Images, 1) ~= 784 || size(app.Labels, 1) ~= 6 || size(app.Images, 2) ~= size(app.Labels, 2)
                    app.InfoTextArea.Value = [app.InfoTextArea.Value; 'Erro: Dimensões inválidas. Imagens devem ser 784xN, rótulos 6xN.'];
                    scroll(app.InfoTextArea, 'bottom');
                    return;
                end
        
                net = app.Network;
                images = app.Images;
                labels = app.Labels;
                classNames = {'circle', 'kite', 'parallelogram', 'square', 'trapezoid', 'triangle'};
        
                outputs = net(images);
                if size(outputs, 1) ~= 6 || size(outputs, 2) ~= size(images, 2)
                    app.InfoTextArea.Value = [app.InfoTextArea.Value; 'Erro: Saída da rede com dimensões inválidas. Esperado 6xN.'];
                    scroll(app.InfoTextArea, 'bottom');
                    return;
                end
                [~, predicted] = max(outputs);
                [~, expected] = max(labels);
        
                accuracy = sum(predicted == expected) / length(expected) * 100;
        
                numImages = size(images, 2);
                tableData = cell(numImages, 4);
                for i = 1:numImages
                    if isfield(app, 'ImageNames') && length(app.ImageNames) >= i && ~isempty(app.ImageNames{i})
                        tableData{i, 1} = app.ImageNames{i};
                    else
                        tableData{i, 1} = sprintf('Imagem %d', i);
                    end
                    tableData{i, 2} = classNames{predicted(i)};
                    tableData{i, 3} = classNames{expected(i)};
                    tableData{i, 4} = 'Correto';
                    if predicted(i) ~= expected(i)
                        tableData{i, 4} = 'Incorreto';
                    end
                end
        
                app.UITable.ColumnName = {'Nome da Imagem', 'Classe Predita', 'Classe Esperada', 'Correção'};
                app.UITable.ColumnWidth = {200, 100, 100, 100};
                app.UITable.Data = tableData;
        
                app.InfoTextArea.Value = [app.InfoTextArea.Value; sprintf('Classificação concluída. Accuracy: %.2f%% (%d/%d imagens corretas)', accuracy, sum(predicted == expected), numImages)];
                scroll(app.InfoTextArea, 'bottom');
        
            catch err
                app.InfoTextArea.Value = [app.InfoTextArea.Value; sprintf('Erro durante a classificação: %s', err.message)];
                scroll(app.InfoTextArea, 'bottom');
            end
        end

        % Button pushed function: ExportTableButton
        function ExportTableButtonPushed(app, event)
            if isempty(app.UITable.Data)
                app.InfoTextArea.Value = [app.InfoTextArea.Value; 'Erro: Tabela vazia. Execute a classificação primeiro.'];
                scroll(app.InfoTextArea, 'bottom');
                return;
            end

            [file, path] = uiputfile('*.xlsx', 'Exportar Tabela', 'eResults.xlsx');

            if file == 0
                return;
            end

            writetable(cell2table(app.UITable.Data, 'VariableNames', app.UITable.ColumnName), fullfile(path, file));
            app.InfoTextArea.Value = [app.InfoTextArea.Value; sprintf('Tabela exportada para %s.', fullfile(path, file))];
            scroll(app.InfoTextArea, 'bottom');
        end

        % Button pushed function: ClassifySingleImageButton
        function ClassifySingleImageButtonPushed(app, event)
            if isempty(app.Network)
                app.InfoTextArea.Value = [app.InfoTextArea.Value; 'Erro: Nenhuma rede carregada.'];
                return;
            end

            [file, path] = uigetfile('*.png', 'Selecionar Imagem');

            if file == 0
                return;
            end

            img = imread(fullfile(path, file));
            if size(img, 3) == 3
                img = rgb2gray(img);
            end
            img = imresize(img, [28 28]);
            img = imbinarize(img);

            output = app.Network(img(:));
            [~, classIdx] = max(output);
            classNames = {'circle', 'kite', 'parallelogram', 'square', 'trapezoid', 'triangle'};
            app.InfoTextArea.Value = [app.InfoTextArea.Value; sprintf('Imagem %s: Classe predita: %s', file, classNames{classIdx})];
            scroll(app.InfoTextArea, 'bottom');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.HelpMenu_2 = uimenu(app.HelpMenu);
            app.HelpMenu_2.MenuSelectedFcn = createCallbackFcn(app, @HelpMenu_2Selected, true);
            app.HelpMenu_2.Text = 'Help';
            app.ActivationFcnDropDownLabel = uilabel(app.UIFigure);
            app.ActivationFcnDropDownLabel.Text = 'ActivationFcn';
            app.ActivationFcnDropDown = uidropdown(app.UIFigure);
            app.ActivationFcnDropDown.Items = {'logsig', 'poslin', 'tansig', 'purelin', 'radbas', 'satlin', 'satlins', 'tribas'};
            app.ActivationFcnDropDown.Value = 'logsig';
            app.EndTrainFcnDropDownLabel = uilabel(app.UIFigure);
            app.EndTrainFcnDropDownLabel.Text = 'EndTrainFcn';
            app.EndTrainFcnDropDown = uidropdown(app.UIFigure);
            app.EndTrainFcnDropDown.Items = {'softmax', 'purelin', 'tansig'};
            app.EndTrainFcnDropDown.Value = 'softmax';
            app.TrainFcnDropDownLabel = uilabel(app.UIFigure);
            app.TrainFcnDropDownLabel.Text = 'TrainFcn';
            app.TrainFcnDropDown = uidropdown(app.UIFigure);
            app.TrainFcnDropDown.Items = {'trainscg', 'trainrp', 'trainlm', 'trainbfg', 'traincgb'};
            app.TrainFcnDropDown.Value = 'trainscg';
            app.PerformFcnDropDownLabel = uilabel(app.UIFigure);
            app.PerformFcnDropDownLabel.Text = 'PerformFcn';
            app.PerformFcnDropDown = uidropdown(app.UIFigure);
            app.PerformFcnDropDown.Items = {'crossentropy', 'mse', 'mae', 'sse'};
            app.PerformFcnDropDown.Value = 'crossentropy';
            app.TopologyEditFieldLabel = uilabel(app.UIFigure);
            app.TopologyEditFieldLabel.Text = 'Topology';
            app.TopologyEditField = uieditfield(app.UIFigure, 'text');
            app.TopologyEditField.Placeholder = '50 30';
            app.TopologyEditField.Value = '50 30';
            app.TextAreaLabel = uilabel(app.UIFigure);
            app.TextAreaLabel.Text = 'Info';
            app.InfoTextArea = uitextarea(app.UIFigure);
            app.InfoTextArea.Editable = 'off';
            app.TrainRatioEditFieldLabel = uilabel(app.UIFigure);
            app.TrainRatioEditFieldLabel.Text = 'TrainRatio';
            app.TrainRatioEditField = uieditfield(app.UIFigure, 'numeric');
            app.TrainRatioEditField.Placeholder = '0.8';
            app.TrainRatioEditField.Value = 0.8;
            app.ValRatioEditFieldLabel = uilabel(app.UIFigure);
            app.ValRatioEditFieldLabel.Text = 'ValRatio';
            app.ValRatioEditField = uieditfield(app.UIFigure, 'numeric');
            app.ValRatioEditField.Placeholder = '0.1';
            app.ValRatioEditField.Value = 0.1;
            app.TestRatioEditFieldLabel = uilabel(app.UIFigure);
            app.TestRatioEditFieldLabel.Text = 'TestRatio';
            app.TestRatioEditField = uieditfield(app.UIFigure, 'numeric');
            app.TestRatioEditField.Placeholder = '0.1';
            app.TestRatioEditField.Value = 0.1;
            app.TrainButton = uibutton(app.UIFigure, 'push');
            app.TrainButton.ButtonPushedFcn = createCallbackFcn(app, @TrainButtonPushed, true);
            app.TrainButton.Text = 'Train';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.LoadDatasetButton = uibutton(app.UIFigure, 'push');
            app.LoadDatasetButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDatasetButtonPushed, true);
            app.LoadDatasetButton.Text = 'LoadDataset';
            app.LoadedDatasetTextAreaLabel = uilabel(app.UIFigure);
            app.LoadedDatasetTextAreaLabel.Text = 'LoadedDataset';
            app.LoadedDatasetTextArea = uitextarea(app.UIFigure);
            app.LoadedDatasetTextArea.Editable = 'off';
            app.ClearInfoButton = uibutton(app.UIFigure, 'push');
            app.ClearInfoButton.ButtonPushedFcn = createCallbackFcn(app, @ClearInfoButtonPushed, true);
            app.ClearInfoButton.Text = 'ClearInfo';
            app.ExportTableButton = uibutton(app.UIFigure, 'push');
            app.ExportTableButton.ButtonPushedFcn = createCallbackFcn(app, @ExportTableButtonPushed, true);
            app.ExportTableButton.Text = 'ExportTable';
            app.LoadedNetTextAreaLabel = uilabel(app.UIFigure);
            app.LoadedNetTextAreaLabel.Text = 'LoadedNet';
            app.LoadedNetTextArea = uitextarea(app.UIFigure);
            app.LoadedNetTextArea.Editable = 'off';
            app.LoadNetButton = uibutton(app.UIFigure, 'push');
            app.LoadNetButton.ButtonPushedFcn = createCallbackFcn(app, @LoadNetButtonPushed, true);
            app.LoadNetButton.Text = 'LoadNet';
            app.SaveNetButton = uibutton(app.UIFigure, 'push');
            app.SaveNetButton.ButtonPushedFcn = createCallbackFcn(app, @SaveNetButtonPushed, true);
            app.SaveNetButton.Enable = 'off';
            app.SaveNetButton.Text = 'SaveNet';
            app.ClearNetButton = uibutton(app.UIFigure, 'push');
            app.ClearNetButton.ButtonPushedFcn = createCallbackFcn(app, @ClearNetButtonPushed, true);
            app.ClearNetButton.Text = 'ClearNet';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.ClassifySingleImageButton = uibutton(app.UIFigure, 'push');
            app.ClassifySingleImageButton.ButtonPushedFcn = createCallbackFcn(app, @ClassifySingleImageButtonPushed, true);
            app.ClassifySingleImageButton.Text = 'ClassifySingleImage';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = xE

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end